classdef MATDSSApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MATDSSApplicationUIFigure      matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        AboutMenu_2                    matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        DetailsDERSTabGroup            matlab.ui.container.TabGroup
        DetailsTab                     matlab.ui.container.Tab
        GridLayout37                   matlab.ui.container.GridLayout
        DetailsTextArea                matlab.ui.control.TextArea
        DERStatusTab                   matlab.ui.container.Tab
        GridLayout30                   matlab.ui.container.GridLayout
        GridLayout11                   matlab.ui.container.GridLayout
        DERStatusTable                 matlab.ui.control.Table
        Panel_54                       matlab.ui.container.Panel
        GridLayout29                   matlab.ui.container.GridLayout
        ProgressBarStatus              matlab.ui.control.Label
        Panel_55                       matlab.ui.container.Panel
        GridLayout31                   matlab.ui.container.GridLayout
        StatusLabel                    matlab.ui.control.Label
        GotoDetailsButton              matlab.ui.control.Button
        ProgressBar1                   matlab.ui.control.Button
        Panel_18                       matlab.ui.container.Panel
        GridLayout28                   matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        SimulationTab                  matlab.ui.container.Tab
        GridLayout35                   matlab.ui.container.GridLayout
        Panel_23                       matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        Panel_25                       matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        Panel_28                       matlab.ui.container.Panel
        GridLayout32                   matlab.ui.container.GridLayout
        PlotLegendPanel                matlab.ui.container.Panel
        GridLayout33                   matlab.ui.container.GridLayout
        PlotLegend                     matlab.ui.container.Panel
        PlotPropertiesPanel            matlab.ui.container.Panel
        GridLayout13                   matlab.ui.container.GridLayout
        Panel_39                       matlab.ui.container.Panel
        GridLayout44                   matlab.ui.container.GridLayout
        PlotButton                     matlab.ui.control.Button
        UpdateListButton               matlab.ui.control.Button
        PPPTabGroup                    matlab.ui.container.TabGroup
        PPPP0Tab                       matlab.ui.container.Tab
        GridLayout14                   matlab.ui.container.GridLayout
        SimulatedPanel                 matlab.ui.container.Panel
        GridLayout43_2                 matlab.ui.container.GridLayout
        P0SimListBox                   matlab.ui.control.ListBox
        MeasuredPanel                  matlab.ui.container.Panel
        GridLayout43                   matlab.ui.container.GridLayout
        P0MeasListBox                  matlab.ui.control.ListBox
        PPPCA_P0Tab                    matlab.ui.container.Tab
        GridLayout14_9                 matlab.ui.container.GridLayout
        SimulatedPanel_2               matlab.ui.container.Panel
        GridLayout43_4                 matlab.ui.container.GridLayout
        CAP0SimListBox                 matlab.ui.control.ListBox
        MeasuredPanel_2                matlab.ui.container.Panel
        GridLayout43_3                 matlab.ui.container.GridLayout
        CAP0MeasListBox                matlab.ui.control.ListBox
        PPPP_DERTab                    matlab.ui.container.Tab
        GridLayout14_10                matlab.ui.container.GridLayout
        SimulatedPanel_3               matlab.ui.container.Panel
        GridLayout43_6                 matlab.ui.container.GridLayout
        P_DERSimListBox                matlab.ui.control.ListBox
        MeasuredPanel_3                matlab.ui.container.Panel
        GridLayout43_5                 matlab.ui.container.GridLayout
        P_DERMeasListBox               matlab.ui.control.ListBox
        PPPQ0Tab                       matlab.ui.container.Tab
        GridLayout14_11                matlab.ui.container.GridLayout
        SimulatedPanel_4               matlab.ui.container.Panel
        GridLayout43_8                 matlab.ui.container.GridLayout
        Q0SimListBox                   matlab.ui.control.ListBox
        MeasuredPanel_4                matlab.ui.container.Panel
        GridLayout43_7                 matlab.ui.container.GridLayout
        Q0MeasListBox                  matlab.ui.control.ListBox
        PPPCA_Q0Tab                    matlab.ui.container.Tab
        GridLayout14_12                matlab.ui.container.GridLayout
        SimulatedPanel_5               matlab.ui.container.Panel
        GridLayout43_10                matlab.ui.container.GridLayout
        CAQ0SimListBox                 matlab.ui.control.ListBox
        MeasuredPanel_5                matlab.ui.container.Panel
        GridLayout43_9                 matlab.ui.container.GridLayout
        CAQ0MeasListBox                matlab.ui.control.ListBox
        PPPQ_DERTab                    matlab.ui.container.Tab
        GridLayout14_13                matlab.ui.container.GridLayout
        SimulatedPanel_6               matlab.ui.container.Panel
        GridLayout43_12                matlab.ui.container.GridLayout
        Q_DERSimListBox                matlab.ui.control.ListBox
        MeasuredPanel_6                matlab.ui.container.Panel
        GridLayout43_11                matlab.ui.container.GridLayout
        Q_DERMeasListBox               matlab.ui.control.ListBox
        PPPVoltageProfileTab           matlab.ui.container.Tab
        GridLayout14_14                matlab.ui.container.GridLayout
        SimulatedPanel_7               matlab.ui.container.Panel
        GridLayout43_14                matlab.ui.container.GridLayout
        VSimListBox                    matlab.ui.control.ListBox
        MeasuredPanel_7                matlab.ui.container.Panel
        GridLayout43_13                matlab.ui.container.GridLayout
        VMeasListBox                   matlab.ui.control.ListBox
        PPPCurrentProfileTab           matlab.ui.container.Tab
        GridLayout14_15                matlab.ui.container.GridLayout
        SimulatedPanel_8               matlab.ui.container.Panel
        GridLayout43_16                matlab.ui.container.GridLayout
        ISimListBox                    matlab.ui.control.ListBox
        MeasuredPanel_8                matlab.ui.container.Panel
        GridLayout43_15                matlab.ui.container.GridLayout
        IMeasListBox                   matlab.ui.control.ListBox
        PlotTabGroup                   matlab.ui.container.TabGroup
        P0Tab                          matlab.ui.container.Tab
        GridLayoutP0                   matlab.ui.container.GridLayout
        P0Panel                        matlab.ui.container.Panel
        CA_P0Tab                       matlab.ui.container.Tab
        GridLayoutQ0                   matlab.ui.container.GridLayout
        CA_P0Panel                     matlab.ui.container.Panel
        P_DERTab                       matlab.ui.container.Tab
        GridLayoutP_DER                matlab.ui.container.GridLayout
        P_DERPanel                     matlab.ui.container.Panel
        Q0Tab                          matlab.ui.container.Tab
        GridLayoutQ0_2                 matlab.ui.container.GridLayout
        Q0Panel                        matlab.ui.container.Panel
        CA_Q0Tab                       matlab.ui.container.Tab
        GridLayoutQ0_3                 matlab.ui.container.GridLayout
        CA_Q0Panel                     matlab.ui.container.Panel
        Q_DER                          matlab.ui.container.Tab
        GridLayoutQ_DER                matlab.ui.container.GridLayout
        Q_DERPanel                     matlab.ui.container.Panel
        VoltageProfileTab              matlab.ui.container.Tab
        GridLayoutV                    matlab.ui.container.GridLayout
        VoltagePanel                   matlab.ui.container.Panel
        CurrentProfileTab              matlab.ui.container.Tab
        GridLayoutI                    matlab.ui.container.GridLayout
        CurrentPanel                   matlab.ui.container.Panel
        LiveMeasurementsTab            matlab.ui.container.Tab
        GridLayout_2                   matlab.ui.container.GridLayout
        VPanel                         matlab.ui.container.Panel
        GridLayout2_5                  matlab.ui.container.GridLayout
        VTable                         matlab.ui.control.Table
        IPanel                         matlab.ui.container.Panel
        GridLayout2_4                  matlab.ui.container.GridLayout
        ITable                         matlab.ui.control.Table
        PPanel                         matlab.ui.container.Panel
        GridLayout2_3                  matlab.ui.container.GridLayout
        PTable                         matlab.ui.control.Table
        QPanel                         matlab.ui.container.Panel
        GridLayout2_2                  matlab.ui.container.GridLayout
        QTable                         matlab.ui.control.Table
        ConfigurationsTab              matlab.ui.container.Tab
        GridLayout36                   matlab.ui.container.GridLayout
        Panel_57                       matlab.ui.container.Panel
        GridLayout38                   matlab.ui.container.GridLayout
        Panel_34                       matlab.ui.container.Panel
        GridLayout39                   matlab.ui.container.GridLayout
        ClearSimDataButton             matlab.ui.control.Button
        LoadConfigFromSavedSimDataButton  matlab.ui.control.Button
        ManageSimulationSetupDataButton  matlab.ui.control.Button
        GenerateSaveSimulationSetupDataButton  matlab.ui.control.Button
        LoadConfigsButton              matlab.ui.control.Button
        NewDERButton                   matlab.ui.control.Button
        DeleteDERButton                matlab.ui.control.Button
        SaveAllButton                  matlab.ui.control.Button
        SaveButton_2                   matlab.ui.control.Button
        CircuitConfigurationsTabGroup  matlab.ui.container.TabGroup
        DERsTab                        matlab.ui.container.Tab
        DERsGrid                       matlab.ui.container.GridLayout
        DERsTable                      matlab.ui.control.Table
        VITab                          matlab.ui.container.Tab
        GridLayout2_6                  matlab.ui.container.GridLayout
        ITrackingPanel                 matlab.ui.container.Panel
        ITrackingGrid                  matlab.ui.container.GridLayout
        ITrackingListBox               matlab.ui.control.ListBox
        VTrackingPanel                 matlab.ui.container.Panel
        VTrackingGrid                  matlab.ui.container.GridLayout
        VTrackingListBox               matlab.ui.control.ListBox
        ControlAreasTab                matlab.ui.container.Tab
        GridLayout3_2                  matlab.ui.container.GridLayout
        EnableMultiAreaControlStructureCheckBox  matlab.ui.control.CheckBox
        TabGroup2                      matlab.ui.container.TabGroup
        ControlParametersTab           matlab.ui.container.Tab
        ControlParametersTabGrid       matlab.ui.container.GridLayout
        DisturbancePanel               matlab.ui.container.Panel
        GridLayout19_4                 matlab.ui.container.GridLayout
        Panel_56                       matlab.ui.container.Panel
        GridLayout35_2                 matlab.ui.container.GridLayout
        LoadFromExcelButton            matlab.ui.control.Button
        DisableAllDisturbanceButton    matlab.ui.control.Button
        EnableAllDisturbanceButton     matlab.ui.control.Button
        DeleteDisturbanceButton        matlab.ui.control.Button
        AddDisturbanceButton           matlab.ui.control.Button
        DisturbanceTable               matlab.ui.control.Table
        DERiterationsperupdatePanel    matlab.ui.container.Panel
        GridLayout19_3                 matlab.ui.container.GridLayout
        InactiveYetButton_2            matlab.ui.control.StateButton
        LoopsizeEditField              matlab.ui.control.EditField
        LoopsizeEditFieldLabel         matlab.ui.control.Label
        RoURateofUpdatePanel           matlab.ui.container.Panel
        GridLayout19_2                 matlab.ui.container.GridLayout
        InactiveYetButton              matlab.ui.control.StateButton
        RoUEditField                   matlab.ui.control.EditField
        RoUbetweendifferentlevelareasEditFieldLabel  matlab.ui.control.Label
        GeneralControllersSettingsPanel  matlab.ui.container.Panel
        GridLayout19                   matlab.ui.container.GridLayout
        DisableControllersButton       matlab.ui.control.StateButton
        GeneralGainEditField           matlab.ui.control.EditField
        GeneralGainEditFieldLabel      matlab.ui.control.Label
        ControllersSettingsPanel       matlab.ui.container.Panel
        GridLayout7_2                  matlab.ui.container.GridLayout
        ControllersSettingsTable       matlab.ui.control.Table
        ControlAreasSettingsPanel      matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        VDERsTable                     matlab.ui.control.Table
        ControlAreasTable              matlab.ui.control.Table
        AdvancedControlTab             matlab.ui.container.Tab
        GridLayout45                   matlab.ui.container.GridLayout
        DControlPanel                  matlab.ui.container.Panel
        GridLayout48                   matlab.ui.container.GridLayout
        DControlAppliedtoButtonGroup   matlab.ui.container.ButtonGroup
        VDERsonlyButton_2              matlab.ui.control.RadioButton
        DERsVDERsButton_2              matlab.ui.control.RadioButton
        Panel_41                       matlab.ui.container.Panel
        GridLayout49                   matlab.ui.container.GridLayout
        DSignalButtonGroup             matlab.ui.container.ButtonGroup
        OtherButton                    matlab.ui.control.RadioButton
        DControlYButton                matlab.ui.control.RadioButton
        DControlErrorButton            matlab.ui.control.RadioButton
        Panel_40                       matlab.ui.container.Panel
        GridLayout47_2                 matlab.ui.container.GridLayout
        kappa_dzetaEditField           matlab.ui.control.NumericEditField
        kappa_dzetaEditFieldLabel      matlab.ui.control.Label
        kappa_dnuEditField             matlab.ui.control.NumericEditField
        kappa_dnuEditFieldLabel        matlab.ui.control.Label
        kappa_dgammaEditField          matlab.ui.control.NumericEditField
        kappa_dgammaEditFieldLabel     matlab.ui.control.Label
        kappa_dpsiEditField            matlab.ui.control.NumericEditField
        kappa_dpsiEditFieldLabel       matlab.ui.control.Label
        kappa_detaEditField            matlab.ui.control.NumericEditField
        kappa_detaEditFieldLabel       matlab.ui.control.Label
        kappa_dmuEditField             matlab.ui.control.NumericEditField
        kappa_dmuEditFieldLabel        matlab.ui.control.Label
        kappa_dlambdaEditField         matlab.ui.control.NumericEditField
        kappa_dlambdaEditFieldLabel    matlab.ui.control.Label
        KdEditField                    matlab.ui.control.NumericEditField
        KdEditFieldLabel               matlab.ui.control.Label
        EnableDControlCheckBox         matlab.ui.control.CheckBox
        EnablePControlCheckBox         matlab.ui.control.CheckBox
        EnableLPFCheckBox              matlab.ui.control.CheckBox
        PControlPanel                  matlab.ui.container.Panel
        GridLayout50                   matlab.ui.container.GridLayout
        PControlAppliedtoButtonGroup   matlab.ui.container.ButtonGroup
        VDERsonlyButton                matlab.ui.control.RadioButton
        DERsVDERsButton                matlab.ui.control.RadioButton
        Panel_42                       matlab.ui.container.Panel
        GridLayout47_3                 matlab.ui.container.GridLayout
        kappa_pzetaEditField           matlab.ui.control.NumericEditField
        kappa_pzetaEditFieldLabel      matlab.ui.control.Label
        kappa_pnuEditField             matlab.ui.control.NumericEditField
        kappa_pnuEditFieldLabel        matlab.ui.control.Label
        kappa_pgammaEditField          matlab.ui.control.NumericEditField
        kappa_pgammaEditFieldLabel     matlab.ui.control.Label
        kappa_ppsiEditField            matlab.ui.control.NumericEditField
        kappa_ppsiEditFieldLabel       matlab.ui.control.Label
        kappa_petaEditField            matlab.ui.control.NumericEditField
        kappa_petaEditFieldLabel       matlab.ui.control.Label
        kappa_pmuEditField             matlab.ui.control.NumericEditField
        kappa_pmuEditFieldLabel        matlab.ui.control.Label
        kappa_plambdaEditField         matlab.ui.control.NumericEditField
        kappa_plambdaEditFieldLabel    matlab.ui.control.Label
        KpEditField                    matlab.ui.control.NumericEditField
        KpEditFieldLabel               matlab.ui.control.Label
        LowPassFilterPanel             matlab.ui.container.Panel
        GridLayout46                   matlab.ui.container.GridLayout
        TccutoffperiodinsEditField     matlab.ui.control.NumericEditField
        TccutoffperiodinsEditFieldLabel  matlab.ui.control.Label
        OpenDSSFileTab                 matlab.ui.container.Tab
        GridLayout22                   matlab.ui.container.GridLayout
        Panel_31                       matlab.ui.container.Panel
        GridLayout23                   matlab.ui.container.GridLayout
        SaveButton                     matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
        MATDSSOpenDSSText              matlab.ui.control.TextArea
        ConfigurationsPanel            matlab.ui.container.Panel
        GridLayout16                   matlab.ui.container.GridLayout
        SimulationSettingsPanel        matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        Panel_43                       matlab.ui.container.Panel
        GridLayout51                   matlab.ui.container.GridLayout
        LiveDERButton                  matlab.ui.control.StateButton
        Panel_36                       matlab.ui.container.Panel
        GridLayout41                   matlab.ui.container.GridLayout
        TDInterfaceBusPowerSettingsPanel_3  matlab.ui.container.Panel
        GridLayout52                   matlab.ui.container.GridLayout
        TabGroup3                      matlab.ui.container.TabGroup
        PTab                           matlab.ui.container.Tab
        GridLayout53                   matlab.ui.container.GridLayout
        GridLayout18_3                 matlab.ui.container.GridLayout
        Panel_52                       matlab.ui.container.Panel
        GridLayout34_3                 matlab.ui.container.GridLayout
        TrackP0Button                  matlab.ui.control.StateButton
        ChangekWEditField              matlab.ui.control.EditField
        ChangekWEditFieldLabel         matlab.ui.control.Label
        P0SetFunctionButtonGroup       matlab.ui.container.ButtonGroup
        P0RampButton                   matlab.ui.control.RadioButton
        P0UnitStepButton               matlab.ui.control.RadioButton
        P0ConstantButton               matlab.ui.control.RadioButton
        QNotworkingyetTab              matlab.ui.container.Tab
        GridLayout53_2                 matlab.ui.container.GridLayout
        GridLayout18_4                 matlab.ui.container.GridLayout
        Panel_53                       matlab.ui.container.Panel
        GridLayout34_4                 matlab.ui.container.GridLayout
        TrackQ0Button                  matlab.ui.control.StateButton
        ChangekVarEditField            matlab.ui.control.EditField
        ChangekVarEditFieldLabel       matlab.ui.control.Label
        Q0SetFunctionButtonGroup       matlab.ui.container.ButtonGroup
        Q0RampButton                   matlab.ui.control.RadioButton
        Q0UnitStepButton               matlab.ui.control.RadioButton
        Q0ConstantButton               matlab.ui.control.RadioButton
        Panel_38                       matlab.ui.container.Panel
        GridLayout42                   matlab.ui.container.GridLayout
        ReloadSimDataButton            matlab.ui.control.Button
        SaveAllBeforeSimulateButton    matlab.ui.control.StateButton
        Panel_20                       matlab.ui.container.Panel
        GridLayout24                   matlab.ui.container.GridLayout
        Panel_22                       matlab.ui.container.Panel
        GridLayout25                   matlab.ui.container.GridLayout
        ContStButton                   matlab.ui.control.Button
        StepButton                     matlab.ui.control.Button
        PauseButton                    matlab.ui.control.StateButton
        GridLayout21                   matlab.ui.container.GridLayout
        Panel_35                       matlab.ui.container.Panel
        GridLayout40                   matlab.ui.container.GridLayout
        UseSimDataButton               matlab.ui.control.StateButton
        LiveMeasButton                 matlab.ui.control.StateButton
        CustomCheckBox                 matlab.ui.control.CheckBox
        ExportButton                   matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        SimulateButton                 matlab.ui.control.Button
        TimeSettingsPanel              matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        StabilizationTimeEditField     matlab.ui.control.EditField
        StabilizationTimeEditFieldLabel  matlab.ui.control.Label
        sLabel_2                       matlab.ui.control.Label
        MeasDelayEditField             matlab.ui.control.EditField
        MeasDelayEditFieldLabel        matlab.ui.control.Label
        msLabel_4                      matlab.ui.control.Label
        MeasTimeStepEditField          matlab.ui.control.EditField
        MeasTimeStepEditFieldLabel     matlab.ui.control.Label
        msLabel_3                      matlab.ui.control.Label
        ContTimeStepEditField          matlab.ui.control.EditField
        ContTimeStepEditFieldLabel     matlab.ui.control.Label
        msLabel_2                      matlab.ui.control.Label
        SimTimeStepEditField           matlab.ui.control.EditField
        SimTimeStepEditFieldLabel      matlab.ui.control.Label
        msLabel                        matlab.ui.control.Label
        DurationEditField              matlab.ui.control.EditField
        DurationEditFieldLabel         matlab.ui.control.Label
        sLabel                         matlab.ui.control.Label
        OpenDSSFilesPanel              matlab.ui.container.Panel
        GridLayout17                   matlab.ui.container.GridLayout
        BrowseButton                   matlab.ui.control.Button
        OpenDSSFilesListBox            matlab.ui.control.ListBox
    end


    properties (Access = private)
        Configuration; % Handle for the configuration app window (Deprecated!)
        Measurements; % Handle for the measurements app window (Deprecated!)
        ManageSimData; % Handle for ManageSimData app Window
    end

    properties (Access = public)
        % Main variable that contains all information related to the current run/simulation
        MyRun; 
        
        
        % Change app version here (and in the App panel on the right). 
        AppVer = '0.95';
        
        
        % A bunch of variables, will double check the list in the future
        % versions (some are not used)
        MATDSSRunVariables = struct;
        DisturbanceTableData = [];
        MATDSSExcelSheet;
        ControlAreasTableData;
        VDERsTableData;
        mypool;
        LoadedSimData;
        Main;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % startupFcn(app)
            % This function is called when MATDSS Application is opened for the first time. The function will:
            %    * Include ".\Functions\App" folder to MATLAB paths to be able to call "MATDSSAPP_Defaults" function.
            %    * Center the GUI on the screen (minimum resolution supported is FHD).
            %       > Otherwise, the window will go beyond the visible area on screen

            app.SaveAllBeforeSimulateButton.Text = "Save All";
            addpath Functions\App; %Location of MATDSS_AppDefaults Function


            % app.MyRun Setup - initialization/reinitialization
            % This variable will always contain the data of the current
            % simulation. If any error is encountered, then check this
            % variable to know what is happening within the
            % system/controllers.
            %
            app.MyRun.MATDSS = [];
            app.MyRun.DER = [];
            app.MyRun.Plot.PlotHandle = [];
            app.MyRun.Plot.PPP = struct; % Plot Properties Panel
            app.MyRun.Plot.Colors =[...
                '#0072BD';... % 1 new MATLAB blue
                '#D95319';... % 2 Orange
                '#EDB120';... % 3 Yellow
                '#7E2F8E';... % 4 Magenta
                '#77AC30';... % 5 Green
                '#4DBEEE';... % 6 Sky blue
                '#A2142F';... % 7 Dark red (wine color)
                '#767676';... % 8 Gray
                '#303030';... % 9 Dark Gray
                '#000000';... % 10 Black
                '#3B7558';... % 11 Dark Grass Green
                '#D0021B';... % 12 Nice red
                '#8B572A';... % 13 Brown
                ];
            app.MyRun.SimulationCompleted = false; % Flag used for Plot update function to identify when to update plots if Plot Properties Panel options changes.


            % Here the app initiallization starts!
            MATDSSApp_Status(app,'Initializing (MATDSS Application)',0);

            % Fixing the window location and size - Current design is for
            % at least FHD screen with 100% scale window.
            % A future release might consider a better design tool (maybe
            % python) to better utilize the space and maybe consider having
            % additional window for setup that can be kept in the
            % background!
            

            myscreen = get(0,'ScreenSize'); % Get screen resolution info
            mywindow = [1920,1080]; % Set app window size here
            sp = [(myscreen(3)-mywindow(1))/2, (myscreen(4)-mywindow(2))/2]; % Calculate the correct starting point to get the app centered on the screen
            app.MATDSSApplicationUIFigure.Position = [sp,mywindow]; % set the correct info in the position varible of the app!
            MATDSSApp_Defaults(app); % Reset MATDSSApp GUI to default values and re-initiallize some variables and handles.

            % pause(2); %This is important for the GUI positioninig code above


            EnableMultiAreaControlStructureCheckBoxValueChanged(app, []);

            clearvars -except app

        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            MATDSSApplicationUIFigureCloseRequest(app) %Exiting MATDSSApp GUI
        end

        % Selection changed function: P0SetFunctionButtonGroup
        function P0SetFunctionButtonGroupSelectionChanged(app, event)
            MATDSSApp_P0SetFunButtonGroup(app); % Configure P0Set function buttons and text according to selected option
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % Changing directory to look for DSS Files
            DSSFolder = uigetdir([pwd '\DSS_Files']);

            if ~(DSSFolder == 0)
                try
                    % Search for all dss files that start with "MATDSS_"
                    FilesList = dir([DSSFolder '\MATDSS_*.dss']);
                    app.OpenDSSFilesListBox.Tag = [DSSFolder '\'];
                    FilesListNames = {};
                    for i = 1:length(FilesList)
                        FilesListNames = [FilesListNames; FilesList(i).name];
                    end

                    % Generate the list in the GUI
                    app.OpenDSSFilesListBox.Items = FilesListNames;

                    if isempty(FilesListNames)
                        % Handle the case where no OpenDSS files are found
                        app.OpenDSSFilesListBox.Items = {'(no OpenDSS file is located)'};
                    else
                        MATDSSApp_OpenDSSFilesListBoxValueChangedFcn(app); % Update the options available in the Plot Properties Panel following the saved information/configurations in the Config files.
                    end

                    % Set the focus back to the App Designer window
                    figure(app.MATDSSApplicationUIFigure);
                catch
                    % Handle any unexpected errors here
                    disp('An error occurred while searching for OpenDSS files.');
                end
            end
            
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            
            % If Simulate button is clicked with nothing selected in the
            % OpenDSS Files panel, then select the first entry.
            if isempty(app.OpenDSSFilesListBox.Value)
                app.OpenDSSFilesListBox.Value = app.OpenDSSFilesListBox.Items(1);
            end

            if strcmpi(app.OpenDSSFilesListBox.Value, "(no OpenDSS file is located)")
                MATDSSApp_Details(app,'No OpenDSS file is selected. Browse and select a file before running the simulation.')
            else


                if app.SaveAllBeforeSimulateButton.Value
                    MATDSSApp_Status(app,'Saving Configurations, Please Wait...', 0);

                    for i = 1:size(app.TabGroup.Children,1)
                        app.CircuitConfigurationsTabGroup.SelectedTab = app.CircuitConfigurationsTabGroup.Children(i);
                        app.CircuitConfigurationsTabGroupSelectionChanged(app);
                        MATDSSAppConfiguration_Save(app,app.CircuitConfigurationsTabGroup.Children(i).Title)
                    end

                    MATDSSOpenDSSFileUpdate = app.MATDSSOpenDSSText.Value;
                    MATDSSOpenDSSDERFile = fopen([app.OpenDSSFilesListBox.Tag app.OpenDSSFilesListBox.Value ],'w');
                    for i = 1:length(MATDSSOpenDSSFileUpdate)
                        fprintf(MATDSSOpenDSSDERFile,'%s\n', MATDSSOpenDSSFileUpdate{i});
                    end
                    fclose(MATDSSOpenDSSDERFile);

                    % % Save the OpenDSS File selected in MyRun variable for later use/reference
                    % DSSFileName = [app.OpenDSSFilesListBox.Tag app.OpenDSSFilesListBox.Value];
                    % MATDSSOpenDSSFileText = fileread(DSSFileName);
                    % app.MyRun.MATDSSOpenDSSFile.DSSFileName = DSSFileName;
                    % app.MyRun.MATDSSOpenDSSFile.Text = MATDSSOpenDSSFileText;
                    % if ~app.UseSimDataButton.Value
                    %     app.MyRun.MATDSS =[];
                    % end
                    MATDSSApp_Status(app,'Saving Completed. Checking Saved files!', 0);
                end

                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                MATDSSApp_SimulateButtonFunction(app)
            end
        end

        % Value changed function: PauseButton
        function PauseButtonPushed(app, event)
value = app.PauseButton.Value;

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopButton.Tag = '1';
        end

        % Callback function
        function RefreshButtonPushed(app, event)

            % Re-open the DSS file. Use this button if you want to re-load
            % the file and discard the unsaved-changes you made.
            DSSFileName = [app.OpenDSSFilesListBox.Tag app.OpenDSSFilesListBox.Value];
            MATDSSOpenDSSFileText = fileread(DSSFileName);
            app.MATDSSOpenDSSText.Value = MATDSSOpenDSSFileText;

            % Save the OpenDSS File selected in MyRun variable for later use/reference
            app.MyRun.MATDSSOpenDSSFile.DSSFileName = DSSFileName;
            app.MyRun.MATDSSOpenDSSFile.Text = MATDSSOpenDSSFileText;
        end

        % Button pushed function: SaveButton_2
        function SaveButtonPushed(app, event)
            % this button will save the changes you made to the DSS file.
            % The text box will display all the content of the dss file.
            % This viewer will help you check the file, tweak it from
            % within MATDSS application.


            MATDSSOpenDSSFileUpdate = app.MATDSSOpenDSSText.Value;
            MATDSSOpenDSSDERFile = fopen([app.OpenDSSFilesListBox.Tag app.OpenDSSFilesListBox.Value ],'w');
            for i = 1:length(MATDSSOpenDSSFileUpdate)
                fprintf(MATDSSOpenDSSDERFile,'%s\n', MATDSSOpenDSSFileUpdate{i});
            end
            fclose(MATDSSOpenDSSDERFile);

            % Save the OpenDSS File selected in MyRun variable for later use/reference
            DSSFileName = [app.OpenDSSFilesListBox.Tag app.OpenDSSFilesListBox.Value];
            MATDSSOpenDSSFileText = fileread(DSSFileName);
            app.MyRun.MATDSSOpenDSSFile.DSSFileName = DSSFileName;
            app.MyRun.MATDSSOpenDSSFile.Text = MATDSSOpenDSSFileText;


            % Checking Configuration Files corresponding to the selected
            % OpenDSS file. The
        end

        % Callback function
        function ResetMenuSelected(app, event)
            app.MATDSSApplicationUIFigure.Tag = 'Reset';
            MATDSSApp_Defaults(app);
        end

        % Value changed function: OpenDSSFilesListBox
        function OpenDSSFilesListBoxValueChanged(app, event)
            MATDSSApp_OpenDSSFilesListBoxValueChangedFcn(app);
        end

        % Callback function
        function ConfigurationButtonPushed(app, event)
            % Calling MATDSSApp_GUIChanges to disable buttons and settings
            % in the main app window while working in MATDSSConfiguration
            % MATDSSApp_GUIChanges(app,'Disable_GUI');
            % app.Configuration = MATDSSConfiguration(app);
            app.TabGroup.SelectedTab = app.TabGroup.Children(2);
        end

        % Selection change function: PlotTabGroup
        function PlotTabGroupSelectionChanged(app, event)
            SelectedTab = app.PlotTabGroup.SelectedTab.Title;
            switch SelectedTab
                case [char(916) 'P0']
                    app.PPPTabGroup.SelectedTab = app.PPPTabGroup.Children(1);
                case ['CA-' char(916) 'P0']
                    app.PPPTabGroup.SelectedTab = app.PPPTabGroup.Children(2);
                case 'P_DER'
                    app.PPPTabGroup.SelectedTab = app.PPPTabGroup.Children(3);
                case [char(916) 'Q0']
                    app.PPPTabGroup.SelectedTab = app.PPPTabGroup.Children(4);
                case ['CA-' char(916) 'Q0']
                    app.PPPTabGroup.SelectedTab = app.PPPTabGroup.Children(5);
                case 'Q_DER'
                    app.PPPTabGroup.SelectedTab = app.PPPTabGroup.Children(6);
                case 'Voltage Profile'
                    app.PPPTabGroup.SelectedTab = app.PPPTabGroup.Children(7);
                case 'Current Profile'
                    app.PPPTabGroup.SelectedTab = app.PPPTabGroup.Children(8);
            end

        end

        % Close request function: MATDSSApplicationUIFigure
        function MATDSSApplicationUIFigureCloseRequest(app, event)
            delete(app.ManageSimData)
            delete(app)
        end

        % Selection change function: PPPTabGroup
        function PPPTabGroupSelectionChanged(app, event)
            SelectedTab = app.PPPTabGroup.SelectedTab.Title;
            switch SelectedTab
                case [char(916) 'P0']
                    app.PlotTabGroup.SelectedTab = app.PlotTabGroup.Children(1);
                case ['CA-' char(916) 'P0']
                    app.PlotTabGroup.SelectedTab = app.PlotTabGroup.Children(2);
                case 'P_DER'
                    app.PlotTabGroup.SelectedTab = app.PlotTabGroup.Children(3);
                case [char(916) 'Q0']
                    app.PlotTabGroup.SelectedTab = app.PlotTabGroup.Children(4);
                case ['CA-' char(916) 'Q0']
                    app.PlotTabGroup.SelectedTab = app.PlotTabGroup.Children(5);
                case 'Q_DER'
                    app.PlotTabGroup.SelectedTab = app.PlotTabGroup.Children(6);
                case 'VP'
                    app.PlotTabGroup.SelectedTab = app.PlotTabGroup.Children(7);
                case 'IP'
                    app.PlotTabGroup.SelectedTab = app.PlotTabGroup.Children(8);
            end
        end

        % Callback function
        function P0MeasTreeCheckedNodesChanged(app, event)
            MATDSSApp_Plot(app,app.P0MeasTree.Tag);
        end

        % Callback function
        function CurrentProfileSimTreeCheckedNodesChanged(app, event)
            MATDSSApp_Plot(app,app.CurrentProfileMeasTree.Tag);
        end

        % Callback function
        function CurrentProfileMeasTreeCheckedNodesChanged(app, event)
            MATDSSApp_Plot(app,app.CurrentProfileMeasTree.Tag);

        end

        % Callback function
        function VoltageProfileMeasTreeCheckedNodesChanged(app, event)
            MATDSSApp_Plot(app,app.VoltageProfileMeasTree.Tag);
        end

        % Callback function
        function VoltageProfileSimTreeCheckedNodesChanged(app, event)
            MATDSSApp_Plot(app,app.VoltageProfileMeasTree.Tag);
        end

        % Callback function
        function Q_DERSimTreeCheckedNodesChanged(app, event)
            MATDSSApp_Plot(app,app.Q_DERMeasTree.Tag);
        end

        % Callback function
        function Q_DERMeasTreeCheckedNodesChanged(app, event)
            MATDSSApp_Plot(app,app.Q_DERMeasTree.Tag);
        end

        % Callback function
        function Q0SimTreeCheckedNodesChanged(app, event)
            MATDSSApp_Plot(app,app.Q0MeasTree.Tag);
        end

        % Callback function
        function Q0MeasTreeCheckedNodesChanged(app, event)
            MATDSSApp_Plot(app,app.Q0MeasTree.Tag);
        end

        % Callback function
        function P_DERSimTreeCheckedNodesChanged(app, event)
            MATDSSApp_Plot(app,app.P_DERMeasTree.Tag);
        end

        % Callback function
        function P_DERMeasTreeCheckedNodesChanged(app, event)
            MATDSSApp_Plot(app,app.P_DERMeasTree.Tag);
        end

        % Callback function
        function P0SimTreeCheckedNodesChanged(app, event)
            MATDSSApp_Plot(app,app.P0MeasTree.Tag);
        end

        % Value changed function: LiveMeasButton
        function LiveMeasButtonValueChanged(app, event)
value = app.LiveMeasButton.Value;

        end

        % Callback function
        function CA_P0MeasTreeCheckedNodesChanged(app, event)
            MATDSSApp_Plot(app,app.CA_P0MeasTree.Tag);
        end

        % Callback function
        function CA_P0SimTreeCheckedNodesChanged(app, event)
            MATDSSApp_Plot(app,app.CA_P0SimTree.Tag);
        end

        % Callback function
        function CA_Q0MeasTreeCheckedNodesChanged(app, event)
            MATDSSApp_Plot(app,app.CA_Q0MeasTree.Tag);
        end

        % Callback function
        function CA_Q0SimTreeCheckedNodesChanged(app, event)
            MATDSSApp_Plot(app,app.CA_Q0SimTree.Tag);
        end

        % Button pushed function: StepButton
        function StepButtonPushed(app, event)
            app.StepButton.Tag = "clicked";
            %             MATDSSApp_Details(app,{'Step has been clicked.';['t = ' num2str(app.MATDSSRunVariables.t) ' (= ' num2str(app.MATDSSRunVariables.at) 's)'];' '});
        end

        % Button pushed function: ContStButton
        function ContStButtonPushed(app, event)
            app.ContStButton.Tag = "clicked";
            %             MATDSSApp_Details(app,{'Controller Step has been clicked.';['t = ' num2str(app.MATDSSRunVariables.t) ' (= ' num2str(app.MATDSSRunVariables.at) 's)'];' '});

        end

        % Menu selected function: AboutMenu_2
        function AboutMenu_2Selected(app, event)
            MATDSSApp_AboutWindow();
        end

        % Button pushed function: AddDisturbanceButton
        function AddDisturbanceButtonPushed(app, event)
            i = size(app.DisturbanceTable.Data,1) + 1;
            NewDisturbanceTableValues = {num2str(i), "''", "[1, 2, 3]", "3", "Y", "20", "0.9", "[1, inf]", "T"};
            app.DisturbanceTable.Data = [app.DisturbanceTable.Data; NewDisturbanceTableValues];
            app.DisturbanceTableData = app.DisturbanceTable.Data;
        end

        % Button pushed function: DeleteDisturbanceButton
        function DeleteDisturbanceButtonPushed(app, event)
            app.DisturbanceTable.Data(app.DisturbanceTable.Selection(1),:) = [];
            DisturbanceTableData = app.DisturbanceTable.Data.Variables;
            DisturbanceTableData (:,1) = 1:size(DisturbanceTableData ,1);
            app.DisturbanceTable.Data.Variables = DisturbanceTableData;

            if isempty(app.DisturbanceTable.Selection)
                app.DeleteDisturbanceButton.Enable = false;
            end
            app.DisturbanceTableData = app.DisturbanceTable.Data;
        end

        % Selection changed function: DisturbanceTable
        function DisturbanceTableSelectionChanged(app, event)
            if ~isempty(app.DisturbanceTable.Selection)
                app.DeleteDisturbanceButton.Enable = true;
            end
        end

        % Button pushed function: EnableAllDisturbanceButton
        function EnableAllDisturbanceButtonPushed(app, event)
            DisturbanceTableData = app.DisturbanceTable.Data.Variables;
            if size(DisturbanceTableData,1) > 0
                DisturbanceTableData (:,9) = repmat("T",size(DisturbanceTableData,1),1);
                app.DisturbanceTable.Data.Variables = DisturbanceTableData;
                app.DisturbanceTableData = app.DisturbanceTable.Data;
            end
        end

        % Callback function
        function DisturbanceTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if indices(2) == 9 % enabled column
                if ~(strcmpi(app.DisturbanceTable.Data(indices(1),indices(2)).Variables,'f') || strcmpi(app.DisturbanceTable.Data(indices(1),indices(2)).Variables,'t'))
                    uiwait(msgbox('Write Either T or F!','Error!'));
                    app.DisturbanceTable.Data = app.DisturbanceTableData;
                else
                    app.DisturbanceTable.Data(indices(1),indices(2)).Variables = upper(app.DisturbanceTable.Data(indices(1),indices(2)).Variables);
                    app.DisturbanceTableData = app.DisturbanceTable.Data;
                end
            elseif indices(2) == 5 % enabled column
                if ~(strcmpi(app.DisturbanceTable.Data(indices(1),indices(2)).Variables,'y') || strcmpi(app.DisturbanceTable.Data(indices(1),indices(2)).Variables,'d'))
                    uiwait(msgbox('Write Either Y or D!','Error!'));
                    app.DisturbanceTable.Data = app.DisturbanceTableData;
                else
                    app.DisturbanceTable.Data(indices(1),indices(2)).Variables = upper(app.DisturbanceTable.Data(indices(1),indices(2)).Variables);
                    app.DisturbanceTableData = app.DisturbanceTable.Data;
                end
            end


        end

        % Button pushed function: DisableAllDisturbanceButton
        function DisableAllDisturbanceButtonPushed(app, event)
            DisturbanceTableData = app.DisturbanceTable.Data.Variables;
            if size(DisturbanceTableData,1) > 0
                DisturbanceTableData (:,9) = repmat("F",size(DisturbanceTableData,1),1);
                app.DisturbanceTable.Data.Variables = DisturbanceTableData;
                app.DisturbanceTableData = app.DisturbanceTable.Data;
            end
        end

        % Callback function
        function SaveButton_2Pushed(app, event)
            MATDSSAppConfiguration_Save(app,app.CircuitConfigurationsTabGroup.SelectedTab.Title)
        end

        % Button pushed function: NewDERButton
        function NewDERButtonPushed(app, event)
            i = size(app.DERsTable.Data,1) + 1;
            NewDERTableValues = {num2str(i),strcat("DER_", num2str(i)),"","0.2","PV","[1,2,3]","Wye","3","DSS_load","[20,0,0]","[2,0,0]","-1e6","1e6", "-1e6","1e6","1","1"};
            %             app.DERsTable.Data.Properties.
            app.DERsTable.Data = [app.DERsTable.Data;NewDERTableValues];
        end

        % Button pushed function: DeleteDERButton
        function DeleteDERButtonPushed(app, event)
            app.DERsTable.Data(app.DERsTable.Selection(1),:) = [];
            DERsTableData = app.DERsTable.Data.Variables;
            DERsTableData (:,1) = 1:size(DERsTableData ,1);
            app.DERsTable.Data.Variables = DERsTableData ;

            if isempty(app.DERsTable.Selection)
                app.DeleteDERButton.Enable = false;
            end
        end

        % Callback function: SaveAllBeforeSimulateButton, SaveAllButton
        function SaveAllButtonPushed(app, event)
            MATDSSApp_Status(app,'Saving Configurations', 'Saving Configurations to the Excel Files');
            for i = 1:size(app.TabGroup.Children,1)
                app.CircuitConfigurationsTabGroup.SelectedTab = app.CircuitConfigurationsTabGroup.Children(i);
                app.CircuitConfigurationsTabGroupSelectionChanged(app);
                MATDSSAppConfiguration_Save(app,app.CircuitConfigurationsTabGroup.Children(i).Title)
                MATDSSApp_Status(app, ['Saving Configurations (' num2str(i) ' of ' num2str(size(app.TabGroup.Children,1)) ')']);
            end

            MATDSSApp_Status(app, 'Ready!', 'Saving configurations completed.');
        end

        % Callback function
        function CircuitConfigurationsTabGroupSelectionChanged(app, event)
            MATDSSAppConfiguration_TabSelect(app);
        end

        % Selection changed function: DERsTable
        function DERsTableSelectionChanged(app, event)
            if ~isempty(app.DERsTable.Selection)
                app.DeleteDERButton.Enable = true;
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            MATDSSApp_Export(app);
        end

        % Cell edit callback: ControlAreasTable
        function ControlAreasTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            CheckControlAreasNumbersFlag = false;
            if indices(2) == 1 || indices(2) == 2
                uiwait(msgbox('You cannot change bus names or numbers here!','Error!'));
                app.ControlAreasTable.Data = app.ControlAreasTableData;
            elseif indices(2) == 3
                if isnan(str2double(app.ControlAreasTable.Data(indices(1),indices(2)).Variables)) || mod(str2double(app.ControlAreasTable.Data(indices(1),indices(2)).Variables),1) ~= 0
                    uiwait(msgbox('Control Areas number should be positive integer','Error!'));
                    app.ControlAreasTable.Data = app.ControlAreasTableData;
                else
                    CheckControlAreasNumbersFlag = true;
                end
            elseif indices(2) == 4
                if ~(strcmp(lower(app.ControlAreasTable.Data(indices(1),indices(2)).Variables),'f') || strcmp(lower(app.ControlAreasTable.Data(indices(1),indices(2)).Variables),'t'))
                    uiwait(msgbox('Write Either T or F!','Error!'));
                    app.ControlAreasTable.Data = app.ControlAreasTableData;
                else
                    app.ControlAreasTable.Data(indices(1),indices(2)).Variables = upper(app.ControlAreasTable.Data(indices(1),indices(2)).Variables);
                end

            elseif indices(2) == 5
                % Write code to check that the bus name written is one of
                % the names in the table.


            end

            app.ControlAreasTableData = app.ControlAreasTable.Data;
            if CheckControlAreasNumbersFlag
                MATDSSApp_UpdateCASettings(app);
            end
        end

        % Button pushed function: LoadConfigsButton
        function LoadConfigsButtonPushed(app, event)
            MATDSSApp_Configurations(app)
        end

        % Button pushed function: GenerateSaveSimulationSetupDataButton
        function GenerateSaveSimulationSetupDataButtonPushed(app, event)
            MATDSSApp_GenerateSaveSimData(app);
        end

        % Button pushed function: ManageSimulationSetupDataButton
        function ManageSimulationSetupDataButtonPushed(app, event)
            app.ManageSimData = MATDSSManageSimData(app);
        end

        % Button pushed function: LoadConfigFromSavedSimDataButton
        function LoadConfigFromSavedSimDataButtonPushed(app, event)
            MATDSSApp_LoadSimData(app);
        end

        % Button pushed function: ReloadSimDataButton
        function ReloadSimDataButtonPushed(app, event)
            % confirmation = 'continue';
            % if ~strcmpi(app.OpenDSSFilesListBox.Value, app.LoadedSimData.Name) % Check if loaded sim data are for different opendss file
            %     confirmation = questdlg('Loaded SimData are for different OpenDSS filename. Do you want to continue?', ...
            %         'Confirmation', 'Continue', 'Cancel', 'Cancel');
            % end
            %
            % if strcmpi(confirmation, 'continue')
            %     % First load tables in MATDSSApplication
            %     for i = 1:length(app.CircuitConfigurationsTabGroup.Children)
            %         app.CircuitConfigurationsTabGroup.SelectedTab = app.CircuitConfigurationsTabGroup.Children(i);
            %         MATDSSApp_LoadSimData(app,1);
            %     end
            %     app.ControlAreasTableData = app.ControlAreasTable.Data;
            %
            %     % Loading all control parameters data
            %     app.GeneralGainEditField.Value = app.LoadedSimData.ControlParameters.GeneralGain;
            %     app.RoUEditField.Value = app.LoadedSimData.ControlParameters.RoU;
            %     app.LoopsizeEditField.Value = app.LoadedSimData.ControlParameters.LoopSize;
            %
            %     % Loading Time Settings
            %     app.DurationEditField.Value = app.LoadedSimData.TimeSettings.Duration;
            %     app.SimTimeStepEditField.Value = app.LoadedSimData.TimeSettings.SimTimeStep;
            %     app.ContTimeStepEditField.Value = app.LoadedSimData.TimeSettings.ContTimeStep;
            %     app.MeasTimeStepEditField.Value = app.LoadedSimData.TimeSettings.MeasTimeStep;
            %     app.MeasDelayEditField.Value = app.LoadedSimData.TimeSettings.MeasDelay;
            %     app.StabilizationTimeEditField.Value = app.LoadedSimData.TimeSettings.StabilizationTime;
            %
            %
            %     % Loading Simulation Settings
            %     iP0Set = MATDSS_StrComp({app.P0SetFunctionButtonGroup.Children.Text},app.LoadedSimData.P0SetFunctionSettings.Type);
            %     app.P0SetFunctionButtonGroup.SelectedObject = app.P0SetFunctionButtonGroup.Children(iP0Set);
            %     MATDSSApp_P0SetFunButtonGroup(app);
            %     app.ChangekWEditField.Value = app.LoadedSimData.P0SetFunctionSettings.kWVal;
            %     app.TrackP0Button.Value = app.LoadedSimData.P0SetFunctionSettings.TrackP0Toggle;
            %     pause(2);
            %
            % end
            %
            % MATDSSApp_Details(app,{'Checking saved configurations and matrices with MATDSSApp Tables and OpenDSS file, please wait!'});
            % MATDSSApp_Details(app,{' '});
            %
            % % Check if we have same ybus matrix!
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % %%%%%%%%%%%%               System Initialization               %%%%%%%%%%%%
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Gain = str2double(app.GeneralGainEditField.Value);
            % [MATDSS, DER] = MATDSS_Initialize(app,Gain);
            %
            %
            %
            % if MATDSS.Sim.DSSStartOk % if DSS is loaded and linked successfully
            %
            %
            %     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %     %%%%%%%%%%%%               Creating DER Devices                %%%%%%%%%%%%
            %     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %     [MATDSS, DER] = MATDSS_DER(app,MATDSS,DER);
            %
            %
            %     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %     %%%%%%%%%%%%            Creating Disturbance Loads             %%%%%%%%%%%%
            %     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %     MATDSS = MATDSS_Disturbance(app,MATDSS);
            %
            %     for i = 1:20
            %         MATDSS.Sim.DSSSolution.Solve;
            %     end
            %
            %     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %     %%%%%%%%%%%%                    YBus Matrix                    %%%%%%%%%%%%
            %     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %     MATDSS = MATDSS_YBusMatrix(app,MATDSS,1);
            %
            %
            %     MATDSSApp_Details(app, {'Checking Ybus matrix'});
            %     % Checking loaded Ybus and comparing it with generated yBus now
            %     if sum(sum(app.LoadedSimData.MATDSS.Sim.Ybus-MATDSS.Sim.Ybus))
            %         MATDSSApp_Details(app, {'Warning, Ybus of loaded SimData is different from the one obtained using the tables in Excel files!'});
            %     else
            %         MATDSSApp_Details(app, {'Loaded Ybus matix matches the configurations in Excel files'});
            %     end
            %
            %     app.MyRun.MATDSS = app.LoadedSimData.MATDSS;
            %     app.MyRun.DER = app.LoadedSimData.DER;
            %     % Loading PlotPropertiesPanel Content
            %     MATDSSApp_PlotProperties(app,1); % Don't run MATDSS analysis in this funtion and use the results saved in MyRun (although for diff. openDSS file name)
            % end
            MATDSSApp_Status(app,'Reloading SimData...', 'Reloading SimData into the configurations.');

            MATDSSManageSimDataApp_LoadSimDataFile(app)

            MATDSSApp_Status(app,'Ready!', 'SimData reloaded successfully!')


        end

        % Button pushed function: ClearSimDataButton
        function ClearSimDataButtonPushed(app, event)
            app.LoadedSimData = [];
            app.UseSimDataButton.Value = false;
            app.UseSimDataButton.Enable = false;
            app.ReloadSimDataButton.Enable = false;
            app.LoadConfigFromSavedSimDataButton.Enable = false;
        end

        % Button pushed function: LoadFromExcelButton
        function LoadFromExcelButtonPushed(app, event)
            % Open file selection dialog
            [filename, pathname] = uigetfile('*.xlsx', 'Select Excel file');
            if isequal(filename, 0)
                % User canceled the file selection
                return;
            end

            % Load the Excel file
            try
                % Read the table from the selected sheet
                fullpath = fullfile(pathname, filename);
                % Set the 'ReadVariableNames' option to false if your Excel file does not have variable names in the first row
                opts = detectImportOptions(fullfile(pathname, filename), 'ReadVariableNames', false, 'Sheet', 3);

                % Set the 'VariableTypes' option to 'string' for all variables to force reading as strings
                opts = setvartype(opts, repmat("string", 1, numel(opts.VariableNames)));

                % Read the table from the selected sheet with the modified options
                data = readtable(fullfile(pathname, filename), opts);  % Adjust the sheet index as needed

                % Display the table or perform any other desired operations
                % app.DisturbanceTable.Data = [];
                app.DisturbanceTable.Data = data;
                % disp(data);
            catch exception
                errordlg(['Error loading the Excel file: ' exception.message], 'File Load Error');
            end
        end

        % Button pushed function: GotoDetailsButton
        function GotoDetailsButtonPushed(app, event)
            app.DetailsDERSTabGroup.SelectedTab = app.DetailsDERSTabGroup.Children(1);
            app.TabGroup.SelectedTab = app.TabGroup.Children(1);
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            MATDSSApp_PlotProperties(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            MATDSSApp_Plot(app);
        end

        % Value changed function: EnableLPFCheckBox
        function EnableLPFCheckBoxValueChanged(app, event)
            value = app.EnableLPFCheckBox.Value;
            if value
                app.LowPassFilterPanel.Enable = "on";
            else
                app.LowPassFilterPanel.Enable = "off";
            end
        end

        % Value changed function: EnablePControlCheckBox
        function EnablePControlCheckBoxValueChanged(app, event)
            value = app.EnablePControlCheckBox.Value;
            if value
                app.PControlPanel.Enable = "on";
            else
                app.PControlPanel.Enable = "off";
            end
        end

        % Value changed function: EnableDControlCheckBox
        function EnableDControlCheckBoxValueChanged(app, event)
            value = app.EnableDControlCheckBox.Value;
            if value
                app.DControlPanel.Enable = "on";
            else
                app.DControlPanel.Enable = "off";
            end
        end

        % Value changed function: EnableMultiAreaControlStructureCheckBox
        function EnableMultiAreaControlStructureCheckBoxValueChanged(app, event)
            value = app.EnableMultiAreaControlStructureCheckBox.Value;
            % Toggle the Enable property based on the checkbox value
            app.ControlAreasSettingsPanel.Enable = value;
            app.ControllersSettingsPanel.Enable = value;
            app.DisturbancePanel.Enable = value;
            app.DERiterationsperupdatePanel.Enable = value;
            app.RoURateofUpdatePanel.Enable = value;
            app.GeneralControllersSettingsPanel.Enable = value;
        end

        % Callback function
        function EnableLPFCheckBoxValueChanged2(app, event)
            value = app.EnableLPFCheckBox.Value;
            
        end

        % Callback function
        function stop(app, event)
            
        end

        % Callback function
        function relo(app, event)
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MATDSSApplicationUIFigure = uifigure('Visible', 'off');
            app.MATDSSApplicationUIFigure.Name = 'MATDSS Application - V0.95';
            app.MATDSSApplicationUIFigure.CloseRequestFcn = createCallbackFcn(app, @MATDSSApplicationUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.MATDSSApplicationUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.AboutMenu = uimenu(app.MATDSSApplicationUIFigure);
            app.AboutMenu.Text = 'About';
            app.AboutMenu_2 = uimenu(app.AboutMenu);
            app.AboutMenu_2.MenuSelectedFcn = createCallbackFcn(app, @AboutMenu_2Selected, true);
            app.AboutMenu_2.Text = 'About';
            app.GridLayout = uigridlayout(app.MATDSSApplicationUIFigure);
            app.Panel_18 = uipanel(app.GridLayout);
            app.GridLayout28 = uigridlayout(app.Panel_18);
            app.ConfigurationsPanel = uipanel(app.GridLayout28);
            app.ConfigurationsPanel.Title = 'Configurations';
            app.GridLayout16 = uigridlayout(app.ConfigurationsPanel);
            app.OpenDSSFilesPanel = uipanel(app.GridLayout16);
            app.OpenDSSFilesPanel.Title = 'OpenDSS Files';
            app.GridLayout17 = uigridlayout(app.OpenDSSFilesPanel);
            app.OpenDSSFilesListBox = uilistbox(app.GridLayout17);
            app.OpenDSSFilesListBox.Items = {'No Files Found'};
            app.OpenDSSFilesListBox.ValueChangedFcn = createCallbackFcn(app, @OpenDSSFilesListBoxValueChanged, true);
            app.OpenDSSFilesListBox.Tag = 'OpenDSS Files';
            app.OpenDSSFilesListBox.Value = 'No Files Found';
            app.BrowseButton = uibutton(app.GridLayout17, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.TimeSettingsPanel = uipanel(app.GridLayout16);
            app.TimeSettingsPanel.Title = 'Time Settings';
            app.GridLayout9 = uigridlayout(app.TimeSettingsPanel);
            app.sLabel = uilabel(app.GridLayout9);
            app.sLabel.Text = 's';
            app.DurationEditFieldLabel = uilabel(app.GridLayout9);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.GridLayout9, 'text');
            app.DurationEditField.Tooltip = {'Simulation Time Step (dynamics time steps)'};
            app.DurationEditField.Value = '30';
            app.msLabel = uilabel(app.GridLayout9);
            app.msLabel.Text = 'ms';
            app.SimTimeStepEditFieldLabel = uilabel(app.GridLayout9);
            app.SimTimeStepEditFieldLabel.Text = 'Sim. Time Step';
            app.SimTimeStepEditField = uieditfield(app.GridLayout9, 'text');
            app.SimTimeStepEditField.Tooltip = {'Simulation Time Step (dynamics time steps)'};
            app.SimTimeStepEditField.Value = '10';
            app.msLabel_2 = uilabel(app.GridLayout9);
            app.msLabel_2.Text = 'ms';
            app.ContTimeStepEditFieldLabel = uilabel(app.GridLayout9);
            app.ContTimeStepEditFieldLabel.Tooltip = {'Level 2 Controller Time Steps'};
            app.ContTimeStepEditFieldLabel.Text = 'Cont. Time Step';
            app.ContTimeStepEditField = uieditfield(app.GridLayout9, 'text');
            app.ContTimeStepEditField.Tooltip = {'Simulation Time Step (dynamics time steps)'};
            app.ContTimeStepEditField.Value = '100';
            app.msLabel_3 = uilabel(app.GridLayout9);
            app.msLabel_3.Text = 'ms';
            app.MeasTimeStepEditFieldLabel = uilabel(app.GridLayout9);
            app.MeasTimeStepEditFieldLabel.Text = 'Meas. Time Step';
            app.MeasTimeStepEditField = uieditfield(app.GridLayout9, 'text');
            app.MeasTimeStepEditField.Tooltip = {'Measurements Update Time Steps'};
            app.MeasTimeStepEditField.Value = '100';
            app.msLabel_4 = uilabel(app.GridLayout9);
            app.msLabel_4.Text = 'ms';
            app.MeasDelayEditFieldLabel = uilabel(app.GridLayout9);
            app.MeasDelayEditFieldLabel.Text = 'Meas. Delay';
            app.MeasDelayEditField = uieditfield(app.GridLayout9, 'text');
            app.MeasDelayEditField.Tooltip = {'Simulation Time Step (dynamics time steps)'};
            app.MeasDelayEditField.Value = '0';
            app.sLabel_2 = uilabel(app.GridLayout9);
            app.sLabel_2.Text = 's';
            app.StabilizationTimeEditFieldLabel = uilabel(app.GridLayout9);
            app.StabilizationTimeEditFieldLabel.Text = 'Stabilization Time';
            app.StabilizationTimeEditField = uieditfield(app.GridLayout9, 'text');
            app.StabilizationTimeEditField.Tooltip = {'Stabilization Time (No change in P0Set points)'};
            app.StabilizationTimeEditField.Value = '0';
            app.SimulationSettingsPanel = uipanel(app.GridLayout16);
            app.SimulationSettingsPanel.Title = 'Simulation Settings';
            app.GridLayout7 = uigridlayout(app.SimulationSettingsPanel);
            app.SimulateButton = uibutton(app.GridLayout7, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.StopButton = uibutton(app.GridLayout7, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.GridLayout21 = uigridlayout(app.GridLayout7);
            app.Panel_35 = uipanel(app.GridLayout21);
            app.GridLayout40 = uigridlayout(app.Panel_35);
            app.ExportButton = uibutton(app.GridLayout40, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.CustomCheckBox = uicheckbox(app.GridLayout40);
            app.CustomCheckBox.Text = 'Custom';
            app.LiveMeasButton = uibutton(app.GridLayout40, 'state');
            app.LiveMeasButton.ValueChangedFcn = createCallbackFcn(app, @LiveMeasButtonValueChanged, true);
            app.LiveMeasButton.Text = 'Live Meas';
            app.UseSimDataButton = uibutton(app.GridLayout40, 'state');
            app.UseSimDataButton.Enable = 'off';
            app.UseSimDataButton.Text = 'Use SimData';
            app.Panel_20 = uipanel(app.GridLayout7);
            app.GridLayout24 = uigridlayout(app.Panel_20);
            app.PauseButton = uibutton(app.GridLayout24, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.Panel_22 = uipanel(app.GridLayout24);
            app.GridLayout25 = uigridlayout(app.Panel_22);
            app.StepButton = uibutton(app.GridLayout25, 'push');
            app.StepButton.ButtonPushedFcn = createCallbackFcn(app, @StepButtonPushed, true);
            app.StepButton.Enable = 'off';
            app.StepButton.Text = 'Step';
            app.ContStButton = uibutton(app.GridLayout25, 'push');
            app.ContStButton.ButtonPushedFcn = createCallbackFcn(app, @ContStButtonPushed, true);
            app.ContStButton.Enable = 'off';
            app.ContStButton.Text = 'Cont.St.';
            app.Panel_36 = uipanel(app.GridLayout7);
            app.GridLayout41 = uigridlayout(app.Panel_36);
            app.Panel_38 = uipanel(app.GridLayout41);
            app.GridLayout42 = uigridlayout(app.Panel_38);
            app.SaveAllBeforeSimulateButton = uibutton(app.GridLayout42, 'state');
            app.SaveAllBeforeSimulateButton.ValueChangedFcn = createCallbackFcn(app, @SaveAllButtonPushed, true);
            app.SaveAllBeforeSimulateButton.Tooltip = {'Save All Before Simulate'};
            app.SaveAllBeforeSimulateButton.Text = 'Save All Before Simulate';
            app.ReloadSimDataButton = uibutton(app.GridLayout42, 'push');
            app.ReloadSimDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadSimDataButtonPushed, true);
            app.ReloadSimDataButton.Enable = 'off';
            app.ReloadSimDataButton.Text = 'Reload SimData';
            app.TDInterfaceBusPowerSettingsPanel_3 = uipanel(app.GridLayout41);
            app.TDInterfaceBusPowerSettingsPanel_3.Title = 'TD Interface Bus Power Settings';
            app.GridLayout52 = uigridlayout(app.TDInterfaceBusPowerSettingsPanel_3);
            app.TabGroup3 = uitabgroup(app.GridLayout52);
            app.PTab = uitab(app.TabGroup3);
            app.PTab.Title = 'P';
            app.GridLayout53 = uigridlayout(app.PTab);
            app.GridLayout18_3 = uigridlayout(app.GridLayout53);
            app.P0SetFunctionButtonGroup = uibuttongroup(app.GridLayout18_3);
            app.P0SetFunctionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @P0SetFunctionButtonGroupSelectionChanged, true);
            app.P0ConstantButton = uiradiobutton(app.P0SetFunctionButtonGroup);
            app.P0ConstantButton.Text = 'Constant';
            app.P0UnitStepButton = uiradiobutton(app.P0SetFunctionButtonGroup);
            app.P0UnitStepButton.Text = 'Unit Step';
            app.P0RampButton = uiradiobutton(app.P0SetFunctionButtonGroup);
            app.P0RampButton.Text = 'Ramp';
            app.P0RampButton.Value = true;
            app.Panel_52 = uipanel(app.GridLayout18_3);
            app.GridLayout34_3 = uigridlayout(app.Panel_52);
            app.ChangekWEditFieldLabel = uilabel(app.GridLayout34_3);
            app.ChangekWEditFieldLabel.Text = 'Change (kW)';
            app.ChangekWEditField = uieditfield(app.GridLayout34_3, 'text');
            app.TrackP0Button = uibutton(app.GridLayout34_3, 'state');
            app.TrackP0Button.Text = 'Track P0';
            app.TrackP0Button.Value = true;
            app.QNotworkingyetTab = uitab(app.TabGroup3);
            app.QNotworkingyetTab.Title = 'Q (Not working yet)';
            app.GridLayout53_2 = uigridlayout(app.QNotworkingyetTab);
            app.GridLayout18_4 = uigridlayout(app.GridLayout53_2);
            app.Q0SetFunctionButtonGroup = uibuttongroup(app.GridLayout18_4);
            app.Q0ConstantButton = uiradiobutton(app.Q0SetFunctionButtonGroup);
            app.Q0ConstantButton.Text = 'Constant';
            app.Q0UnitStepButton = uiradiobutton(app.Q0SetFunctionButtonGroup);
            app.Q0UnitStepButton.Text = 'Unit Step';
            app.Q0RampButton = uiradiobutton(app.Q0SetFunctionButtonGroup);
            app.Q0RampButton.Text = 'Ramp';
            app.Q0RampButton.Value = true;
            app.Panel_53 = uipanel(app.GridLayout18_4);
            app.GridLayout34_4 = uigridlayout(app.Panel_53);
            app.ChangekVarEditFieldLabel = uilabel(app.GridLayout34_4);
            app.ChangekVarEditFieldLabel.Text = 'Change (kVar)';
            app.ChangekVarEditField = uieditfield(app.GridLayout34_4, 'text');
            app.TrackQ0Button = uibutton(app.GridLayout34_4, 'state');
            app.TrackQ0Button.Text = 'Track Q0';
            app.TrackQ0Button.Value = true;
            app.Panel_43 = uipanel(app.GridLayout7);
            app.GridLayout51 = uigridlayout(app.Panel_43);
            app.LiveDERButton = uibutton(app.GridLayout51, 'state');
            app.LiveDERButton.Text = 'Live DER';
            app.TabGroup = uitabgroup(app.GridLayout28);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.GridLayout35 = uigridlayout(app.SimulationTab);
            app.Panel_23 = uipanel(app.GridLayout35);
            app.GridLayout2 = uigridlayout(app.Panel_23);
            app.Panel_25 = uipanel(app.GridLayout2);
            app.GridLayout12 = uigridlayout(app.Panel_25);
            app.PlotTabGroup = uitabgroup(app.GridLayout12);
            app.PlotTabGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTabGroupSelectionChanged, true);
            app.P0Tab = uitab(app.PlotTabGroup);
            app.P0Tab.Title = 'ΔP0';
            app.GridLayoutP0 = uigridlayout(app.P0Tab);
            app.P0Panel = uipanel(app.GridLayoutP0);
            app.CA_P0Tab = uitab(app.PlotTabGroup);
            app.CA_P0Tab.Title = 'CA-ΔP0';
            app.GridLayoutQ0 = uigridlayout(app.CA_P0Tab);
            app.CA_P0Panel = uipanel(app.GridLayoutQ0);
            app.P_DERTab = uitab(app.PlotTabGroup);
            app.P_DERTab.Title = 'P_DER';
            app.GridLayoutP_DER = uigridlayout(app.P_DERTab);
            app.P_DERPanel = uipanel(app.GridLayoutP_DER);
            app.Q0Tab = uitab(app.PlotTabGroup);
            app.Q0Tab.Title = 'ΔQ0';
            app.GridLayoutQ0_2 = uigridlayout(app.Q0Tab);
            app.Q0Panel = uipanel(app.GridLayoutQ0_2);
            app.CA_Q0Tab = uitab(app.PlotTabGroup);
            app.CA_Q0Tab.Title = 'CA-ΔQ0';
            app.GridLayoutQ0_3 = uigridlayout(app.CA_Q0Tab);
            app.CA_Q0Panel = uipanel(app.GridLayoutQ0_3);
            app.Q_DER = uitab(app.PlotTabGroup);
            app.Q_DER.Title = 'Q_DER';
            app.GridLayoutQ_DER = uigridlayout(app.Q_DER);
            app.Q_DERPanel = uipanel(app.GridLayoutQ_DER);
            app.VoltageProfileTab = uitab(app.PlotTabGroup);
            app.VoltageProfileTab.Title = 'Voltage Profile';
            app.GridLayoutV = uigridlayout(app.VoltageProfileTab);
            app.VoltagePanel = uipanel(app.GridLayoutV);
            app.CurrentProfileTab = uitab(app.PlotTabGroup);
            app.CurrentProfileTab.Title = 'Current Profile';
            app.GridLayoutI = uigridlayout(app.CurrentProfileTab);
            app.CurrentPanel = uipanel(app.GridLayoutI);
            app.LiveMeasurementsTab = uitab(app.PlotTabGroup);
            app.LiveMeasurementsTab.Title = 'Live Measurements';
            app.GridLayout_2 = uigridlayout(app.LiveMeasurementsTab);
            app.QPanel = uipanel(app.GridLayout_2);
            app.QPanel.Title = 'Q';
            app.GridLayout2_2 = uigridlayout(app.QPanel);
            app.QTable = uitable(app.GridLayout2_2);
            app.QTable.ColumnName = '';
            app.QTable.RowName = {};
            app.PPanel = uipanel(app.GridLayout_2);
            app.PPanel.Title = 'P';
            app.GridLayout2_3 = uigridlayout(app.PPanel);
            app.PTable = uitable(app.GridLayout2_3);
            app.PTable.ColumnName = '';
            app.PTable.RowName = {};
            app.IPanel = uipanel(app.GridLayout_2);
            app.IPanel.Title = 'I';
            app.GridLayout2_4 = uigridlayout(app.IPanel);
            app.ITable = uitable(app.GridLayout2_4);
            app.ITable.ColumnName = '';
            app.ITable.RowName = {};
            app.VPanel = uipanel(app.GridLayout_2);
            app.VPanel.Title = 'V';
            app.VPanel.Tag = 'OFF';
            app.GridLayout2_5 = uigridlayout(app.VPanel);
            app.VTable = uitable(app.GridLayout2_5);
            app.VTable.ColumnName = '';
            app.VTable.RowName = {};
            app.PlotPropertiesPanel = uipanel(app.GridLayout12);
            app.PlotPropertiesPanel.Title = 'Plot Properties';
            app.GridLayout13 = uigridlayout(app.PlotPropertiesPanel);
            app.PPPTabGroup = uitabgroup(app.GridLayout13);
            app.PPPTabGroup.SelectionChangedFcn = createCallbackFcn(app, @PPPTabGroupSelectionChanged, true);
            app.PPPP0Tab = uitab(app.PPPTabGroup);
            app.PPPP0Tab.Title = 'ΔP0';
            app.GridLayout14 = uigridlayout(app.PPPP0Tab);
            app.MeasuredPanel = uipanel(app.GridLayout14);
            app.MeasuredPanel.Title = 'Measured';
            app.GridLayout43 = uigridlayout(app.MeasuredPanel);
            app.P0MeasListBox = uilistbox(app.GridLayout43);
            app.P0MeasListBox.Items = {};
            app.P0MeasListBox.Multiselect = 'on';
            app.P0MeasListBox.Value = {};
            app.SimulatedPanel = uipanel(app.GridLayout14);
            app.SimulatedPanel.Title = 'Simulated';
            app.GridLayout43_2 = uigridlayout(app.SimulatedPanel);
            app.P0SimListBox = uilistbox(app.GridLayout43_2);
            app.P0SimListBox.Items = {};
            app.P0SimListBox.Multiselect = 'on';
            app.P0SimListBox.Value = {};
            app.PPPCA_P0Tab = uitab(app.PPPTabGroup);
            app.PPPCA_P0Tab.Title = 'CA-ΔP0';
            app.GridLayout14_9 = uigridlayout(app.PPPCA_P0Tab);
            app.MeasuredPanel_2 = uipanel(app.GridLayout14_9);
            app.MeasuredPanel_2.Title = 'Measured';
            app.GridLayout43_3 = uigridlayout(app.MeasuredPanel_2);
            app.CAP0MeasListBox = uilistbox(app.GridLayout43_3);
            app.CAP0MeasListBox.Items = {};
            app.CAP0MeasListBox.Multiselect = 'on';
            app.CAP0MeasListBox.Value = {};
            app.SimulatedPanel_2 = uipanel(app.GridLayout14_9);
            app.SimulatedPanel_2.Title = 'Simulated';
            app.GridLayout43_4 = uigridlayout(app.SimulatedPanel_2);
            app.CAP0SimListBox = uilistbox(app.GridLayout43_4);
            app.CAP0SimListBox.Items = {};
            app.CAP0SimListBox.Multiselect = 'on';
            app.CAP0SimListBox.Value = {};
            app.PPPP_DERTab = uitab(app.PPPTabGroup);
            app.PPPP_DERTab.Title = 'P_DER';
            app.GridLayout14_10 = uigridlayout(app.PPPP_DERTab);
            app.MeasuredPanel_3 = uipanel(app.GridLayout14_10);
            app.MeasuredPanel_3.Title = 'Measured';
            app.GridLayout43_5 = uigridlayout(app.MeasuredPanel_3);
            app.P_DERMeasListBox = uilistbox(app.GridLayout43_5);
            app.P_DERMeasListBox.Items = {};
            app.P_DERMeasListBox.Multiselect = 'on';
            app.P_DERMeasListBox.Value = {};
            app.SimulatedPanel_3 = uipanel(app.GridLayout14_10);
            app.SimulatedPanel_3.Title = 'Simulated';
            app.GridLayout43_6 = uigridlayout(app.SimulatedPanel_3);
            app.P_DERSimListBox = uilistbox(app.GridLayout43_6);
            app.P_DERSimListBox.Items = {};
            app.P_DERSimListBox.Multiselect = 'on';
            app.P_DERSimListBox.Value = {};
            app.PPPQ0Tab = uitab(app.PPPTabGroup);
            app.PPPQ0Tab.Title = 'ΔQ0';
            app.GridLayout14_11 = uigridlayout(app.PPPQ0Tab);
            app.MeasuredPanel_4 = uipanel(app.GridLayout14_11);
            app.MeasuredPanel_4.Title = 'Measured';
            app.GridLayout43_7 = uigridlayout(app.MeasuredPanel_4);
            app.Q0MeasListBox = uilistbox(app.GridLayout43_7);
            app.Q0MeasListBox.Items = {};
            app.Q0MeasListBox.Multiselect = 'on';
            app.Q0MeasListBox.Value = {};
            app.SimulatedPanel_4 = uipanel(app.GridLayout14_11);
            app.SimulatedPanel_4.Title = 'Simulated';
            app.GridLayout43_8 = uigridlayout(app.SimulatedPanel_4);
            app.Q0SimListBox = uilistbox(app.GridLayout43_8);
            app.Q0SimListBox.Items = {};
            app.Q0SimListBox.Multiselect = 'on';
            app.Q0SimListBox.Value = {};
            app.PPPCA_Q0Tab = uitab(app.PPPTabGroup);
            app.PPPCA_Q0Tab.Title = 'CA-ΔQ0';
            app.GridLayout14_12 = uigridlayout(app.PPPCA_Q0Tab);
            app.MeasuredPanel_5 = uipanel(app.GridLayout14_12);
            app.MeasuredPanel_5.Title = 'Measured';
            app.GridLayout43_9 = uigridlayout(app.MeasuredPanel_5);
            app.CAQ0MeasListBox = uilistbox(app.GridLayout43_9);
            app.CAQ0MeasListBox.Items = {};
            app.CAQ0MeasListBox.Multiselect = 'on';
            app.CAQ0MeasListBox.Value = {};
            app.SimulatedPanel_5 = uipanel(app.GridLayout14_12);
            app.SimulatedPanel_5.Title = 'Simulated';
            app.GridLayout43_10 = uigridlayout(app.SimulatedPanel_5);
            app.CAQ0SimListBox = uilistbox(app.GridLayout43_10);
            app.CAQ0SimListBox.Items = {};
            app.CAQ0SimListBox.Multiselect = 'on';
            app.CAQ0SimListBox.Value = {};
            app.PPPQ_DERTab = uitab(app.PPPTabGroup);
            app.PPPQ_DERTab.Title = 'Q_DER';
            app.GridLayout14_13 = uigridlayout(app.PPPQ_DERTab);
            app.MeasuredPanel_6 = uipanel(app.GridLayout14_13);
            app.MeasuredPanel_6.Title = 'Measured';
            app.GridLayout43_11 = uigridlayout(app.MeasuredPanel_6);
            app.Q_DERMeasListBox = uilistbox(app.GridLayout43_11);
            app.Q_DERMeasListBox.Items = {};
            app.Q_DERMeasListBox.Multiselect = 'on';
            app.Q_DERMeasListBox.Value = {};
            app.SimulatedPanel_6 = uipanel(app.GridLayout14_13);
            app.SimulatedPanel_6.Title = 'Simulated';
            app.GridLayout43_12 = uigridlayout(app.SimulatedPanel_6);
            app.Q_DERSimListBox = uilistbox(app.GridLayout43_12);
            app.Q_DERSimListBox.Items = {};
            app.Q_DERSimListBox.Multiselect = 'on';
            app.Q_DERSimListBox.Value = {};
            app.PPPVoltageProfileTab = uitab(app.PPPTabGroup);
            app.PPPVoltageProfileTab.Title = 'VP';
            app.GridLayout14_14 = uigridlayout(app.PPPVoltageProfileTab);
            app.MeasuredPanel_7 = uipanel(app.GridLayout14_14);
            app.MeasuredPanel_7.Title = 'Measured';
            app.GridLayout43_13 = uigridlayout(app.MeasuredPanel_7);
            app.VMeasListBox = uilistbox(app.GridLayout43_13);
            app.VMeasListBox.Items = {};
            app.VMeasListBox.Multiselect = 'on';
            app.VMeasListBox.Value = {};
            app.SimulatedPanel_7 = uipanel(app.GridLayout14_14);
            app.SimulatedPanel_7.Title = 'Simulated';
            app.GridLayout43_14 = uigridlayout(app.SimulatedPanel_7);
            app.VSimListBox = uilistbox(app.GridLayout43_14);
            app.VSimListBox.Items = {};
            app.VSimListBox.Multiselect = 'on';
            app.VSimListBox.Value = {};
            app.PPPCurrentProfileTab = uitab(app.PPPTabGroup);
            app.PPPCurrentProfileTab.Title = 'IP';
            app.GridLayout14_15 = uigridlayout(app.PPPCurrentProfileTab);
            app.MeasuredPanel_8 = uipanel(app.GridLayout14_15);
            app.MeasuredPanel_8.Title = 'Measured';
            app.GridLayout43_15 = uigridlayout(app.MeasuredPanel_8);
            app.IMeasListBox = uilistbox(app.GridLayout43_15);
            app.IMeasListBox.Items = {};
            app.IMeasListBox.Multiselect = 'on';
            app.IMeasListBox.Value = {};
            app.SimulatedPanel_8 = uipanel(app.GridLayout14_15);
            app.SimulatedPanel_8.Title = 'Simulated';
            app.GridLayout43_16 = uigridlayout(app.SimulatedPanel_8);
            app.ISimListBox = uilistbox(app.GridLayout43_16);
            app.ISimListBox.Items = {};
            app.ISimListBox.Multiselect = 'on';
            app.ISimListBox.Value = {};
            app.Panel_39 = uipanel(app.GridLayout13);
            app.GridLayout44 = uigridlayout(app.Panel_39);
            app.UpdateListButton = uibutton(app.GridLayout44, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.PlotButton = uibutton(app.GridLayout44, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Panel_28 = uipanel(app.GridLayout12);
            app.GridLayout32 = uigridlayout(app.Panel_28);
            app.PlotLegendPanel = uipanel(app.GridLayout32);
            app.PlotLegendPanel.Title = 'Plot Legend';
            app.GridLayout33 = uigridlayout(app.PlotLegendPanel);
            app.PlotLegend = uipanel(app.GridLayout33);
            app.ConfigurationsTab = uitab(app.TabGroup);
            app.ConfigurationsTab.Title = 'Configurations';
            app.GridLayout36 = uigridlayout(app.ConfigurationsTab);
            app.Panel_57 = uipanel(app.GridLayout36);
            app.GridLayout38 = uigridlayout(app.Panel_57);
            app.CircuitConfigurationsTabGroup = uitabgroup(app.GridLayout38);
            app.DERsTab = uitab(app.CircuitConfigurationsTabGroup);
            app.DERsTab.Title = 'DERs';
            app.DERsGrid = uigridlayout(app.DERsTab);
            app.DERsTable = uitable(app.DERsGrid);
            app.DERsTable.ColumnName = '';
            app.DERsTable.RowName = {};
            app.DERsTable.ColumnEditable = true;
            app.DERsTable.SelectionChangedFcn = createCallbackFcn(app, @DERsTableSelectionChanged, true);
            app.VITab = uitab(app.CircuitConfigurationsTabGroup);
            app.VITab.Title = 'V & I';
            app.GridLayout2_6 = uigridlayout(app.VITab);
            app.VTrackingPanel = uipanel(app.GridLayout2_6);
            app.VTrackingPanel.Title = 'Voltage tracking - Buses & Phases';
            app.VTrackingGrid = uigridlayout(app.VTrackingPanel);
            app.VTrackingListBox = uilistbox(app.VTrackingGrid);
            app.VTrackingListBox.Items = {};
            app.VTrackingListBox.Multiselect = 'on';
            app.VTrackingListBox.Value = {};
            app.ITrackingPanel = uipanel(app.GridLayout2_6);
            app.ITrackingPanel.Title = 'Branch Current tracking - Lines';
            app.ITrackingGrid = uigridlayout(app.ITrackingPanel);
            app.ITrackingListBox = uilistbox(app.ITrackingGrid);
            app.ITrackingListBox.Items = {};
            app.ITrackingListBox.Multiselect = 'on';
            app.ITrackingListBox.Value = {};
            app.ControlAreasTab = uitab(app.CircuitConfigurationsTabGroup);
            app.ControlAreasTab.Title = 'Control Areas';
            app.GridLayout3_2 = uigridlayout(app.ControlAreasTab);
            app.ControlAreasSettingsPanel = uipanel(app.GridLayout3_2);
            app.ControlAreasSettingsPanel.Title = 'Control Areas Settings';
            app.GridLayout5 = uigridlayout(app.ControlAreasSettingsPanel);
            app.ControlAreasTable = uitable(app.GridLayout5);
            app.ControlAreasTable.ColumnName = '';
            app.ControlAreasTable.RowName = {};
            app.ControlAreasTable.ColumnEditable = true;
            app.ControlAreasTable.CellEditCallback = createCallbackFcn(app, @ControlAreasTableCellEdit, true);
            app.VDERsTable = uitable(app.GridLayout5);
            app.VDERsTable.ColumnName = '';
            app.VDERsTable.RowName = {};
            app.VDERsTable.ColumnEditable = true;
            app.ControllersSettingsPanel = uipanel(app.GridLayout3_2);
            app.ControllersSettingsPanel.Title = 'Controllers Settings';
            app.GridLayout7_2 = uigridlayout(app.ControllersSettingsPanel);
            app.ControllersSettingsTable = uitable(app.GridLayout7_2);
            app.ControllersSettingsTable.ColumnName = '';
            app.ControllersSettingsTable.RowName = {};
            app.ControllersSettingsTable.ColumnEditable = true;
            app.TabGroup2 = uitabgroup(app.GridLayout3_2);
            app.ControlParametersTab = uitab(app.TabGroup2);
            app.ControlParametersTab.Title = 'Control Parameters';
            app.ControlParametersTabGrid = uigridlayout(app.ControlParametersTab);
            app.GeneralControllersSettingsPanel = uipanel(app.ControlParametersTabGrid);
            app.GeneralControllersSettingsPanel.Title = 'General Controllers Settings';
            app.GridLayout19 = uigridlayout(app.GeneralControllersSettingsPanel);
            app.GeneralGainEditFieldLabel = uilabel(app.GridLayout19);
            app.GeneralGainEditFieldLabel.Text = 'General Gain';
            app.GeneralGainEditField = uieditfield(app.GridLayout19, 'text');
            app.GeneralGainEditField.Value = '0.02';
            app.DisableControllersButton = uibutton(app.GridLayout19, 'state');
            app.DisableControllersButton.Text = 'Disable Controllers';
            app.RoURateofUpdatePanel = uipanel(app.ControlParametersTabGrid);
            app.RoURateofUpdatePanel.Title = 'RoU (Rate of Update)';
            app.GridLayout19_2 = uigridlayout(app.RoURateofUpdatePanel);
            app.RoUbetweendifferentlevelareasEditFieldLabel = uilabel(app.GridLayout19_2);
            app.RoUbetweendifferentlevelareasEditFieldLabel.Text = 'RoU between different level areas';
            app.RoUEditField = uieditfield(app.GridLayout19_2, 'text');
            app.RoUEditField.Value = '1';
            app.InactiveYetButton = uibutton(app.GridLayout19_2, 'state');
            app.InactiveYetButton.Enable = 'off';
            app.InactiveYetButton.Text = 'Inactive Yet';
            app.DERiterationsperupdatePanel = uipanel(app.ControlParametersTabGrid);
            app.DERiterationsperupdatePanel.Title = 'DER iterations per update';
            app.GridLayout19_3 = uigridlayout(app.DERiterationsperupdatePanel);
            app.LoopsizeEditFieldLabel = uilabel(app.GridLayout19_3);
            app.LoopsizeEditFieldLabel.Text = 'Loop size';
            app.LoopsizeEditField = uieditfield(app.GridLayout19_3, 'text');
            app.LoopsizeEditField.InputType = 'digits';
            app.LoopsizeEditField.Value = '300';
            app.InactiveYetButton_2 = uibutton(app.GridLayout19_3, 'state');
            app.InactiveYetButton_2.Enable = 'off';
            app.InactiveYetButton_2.Text = 'Inactive Yet';
            app.DisturbancePanel = uipanel(app.ControlParametersTabGrid);
            app.DisturbancePanel.Title = 'Disturbance';
            app.GridLayout19_4 = uigridlayout(app.DisturbancePanel);
            app.DisturbanceTable = uitable(app.GridLayout19_4);
            app.DisturbanceTable.ColumnName = '';
            app.DisturbanceTable.RowName = {};
            app.DisturbanceTable.ColumnEditable = true;
            app.DisturbanceTable.SelectionChangedFcn = createCallbackFcn(app, @DisturbanceTableSelectionChanged, true);
            app.DisturbanceTable.Tag = 'uninitialized';
            app.Panel_56 = uipanel(app.GridLayout19_4);
            app.GridLayout35_2 = uigridlayout(app.Panel_56);
            app.AddDisturbanceButton = uibutton(app.GridLayout35_2, 'push');
            app.AddDisturbanceButton.ButtonPushedFcn = createCallbackFcn(app, @AddDisturbanceButtonPushed, true);
            app.AddDisturbanceButton.Text = 'Add';
            app.DeleteDisturbanceButton = uibutton(app.GridLayout35_2, 'push');
            app.DeleteDisturbanceButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteDisturbanceButtonPushed, true);
            app.DeleteDisturbanceButton.Text = 'Delete';
            app.EnableAllDisturbanceButton = uibutton(app.GridLayout35_2, 'push');
            app.EnableAllDisturbanceButton.ButtonPushedFcn = createCallbackFcn(app, @EnableAllDisturbanceButtonPushed, true);
            app.EnableAllDisturbanceButton.Text = 'Enable All';
            app.DisableAllDisturbanceButton = uibutton(app.GridLayout35_2, 'push');
            app.DisableAllDisturbanceButton.ButtonPushedFcn = createCallbackFcn(app, @DisableAllDisturbanceButtonPushed, true);
            app.DisableAllDisturbanceButton.Text = 'Disable All';
            app.LoadFromExcelButton = uibutton(app.GridLayout35_2, 'push');
            app.LoadFromExcelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFromExcelButtonPushed, true);
            app.LoadFromExcelButton.Text = 'Load From Excel';
            app.EnableMultiAreaControlStructureCheckBox = uicheckbox(app.GridLayout3_2);
            app.EnableMultiAreaControlStructureCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableMultiAreaControlStructureCheckBoxValueChanged, true);
            app.EnableMultiAreaControlStructureCheckBox.Text = 'Enable Multi-Area Control Structure';
            app.AdvancedControlTab = uitab(app.CircuitConfigurationsTabGroup);
            app.AdvancedControlTab.Title = 'Advanced Control';
            app.GridLayout45 = uigridlayout(app.AdvancedControlTab);
            app.LowPassFilterPanel = uipanel(app.GridLayout45);
            app.LowPassFilterPanel.Enable = 'off';
            app.LowPassFilterPanel.Title = 'Low-Pass Filter';
            app.GridLayout46 = uigridlayout(app.LowPassFilterPanel);
            app.TccutoffperiodinsEditFieldLabel = uilabel(app.GridLayout46);
            app.TccutoffperiodinsEditFieldLabel.Text = 'Tc (cutoff period in s)';
            app.TccutoffperiodinsEditField = uieditfield(app.GridLayout46, 'numeric');
            app.TccutoffperiodinsEditField.Value = 0.5;
            app.PControlPanel = uipanel(app.GridLayout45);
            app.PControlPanel.Enable = 'off';
            app.PControlPanel.Title = 'P Control';
            app.GridLayout50 = uigridlayout(app.PControlPanel);
            app.Panel_42 = uipanel(app.GridLayout50);
            app.GridLayout47_3 = uigridlayout(app.Panel_42);
            app.KpEditFieldLabel = uilabel(app.GridLayout47_3);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.GridLayout47_3, 'numeric');
            app.kappa_plambdaEditFieldLabel = uilabel(app.GridLayout47_3);
            app.kappa_plambdaEditFieldLabel.Text = 'kappa_p,lambda';
            app.kappa_plambdaEditField = uieditfield(app.GridLayout47_3, 'numeric');
            app.kappa_plambdaEditField.Value = 0.001;
            app.kappa_pmuEditFieldLabel = uilabel(app.GridLayout47_3);
            app.kappa_pmuEditFieldLabel.Text = 'kappa_p,mu';
            app.kappa_pmuEditField = uieditfield(app.GridLayout47_3, 'numeric');
            app.kappa_pmuEditField.Value = 0.001;
            app.kappa_petaEditFieldLabel = uilabel(app.GridLayout47_3);
            app.kappa_petaEditFieldLabel.Text = 'kappa_p,eta';
            app.kappa_petaEditField = uieditfield(app.GridLayout47_3, 'numeric');
            app.kappa_petaEditField.Value = 0.001;
            app.kappa_ppsiEditFieldLabel = uilabel(app.GridLayout47_3);
            app.kappa_ppsiEditFieldLabel.Text = 'kappa_p,psi';
            app.kappa_ppsiEditField = uieditfield(app.GridLayout47_3, 'numeric');
            app.kappa_ppsiEditField.Value = 0.001;
            app.kappa_pgammaEditFieldLabel = uilabel(app.GridLayout47_3);
            app.kappa_pgammaEditFieldLabel.Text = 'kappa_p,gamma';
            app.kappa_pgammaEditField = uieditfield(app.GridLayout47_3, 'numeric');
            app.kappa_pgammaEditField.Value = 1e-12;
            app.kappa_pnuEditFieldLabel = uilabel(app.GridLayout47_3);
            app.kappa_pnuEditFieldLabel.Text = 'kappa_p,nu';
            app.kappa_pnuEditField = uieditfield(app.GridLayout47_3, 'numeric');
            app.kappa_pnuEditField.Value = 1e-12;
            app.kappa_pzetaEditFieldLabel = uilabel(app.GridLayout47_3);
            app.kappa_pzetaEditFieldLabel.Text = 'kappa_p,zeta';
            app.kappa_pzetaEditField = uieditfield(app.GridLayout47_3, 'numeric');
            app.kappa_pzetaEditField.Value = 1e-07;
            app.PControlAppliedtoButtonGroup = uibuttongroup(app.GridLayout50);
            app.PControlAppliedtoButtonGroup.Title = 'Applied to';
            app.DERsVDERsButton = uiradiobutton(app.PControlAppliedtoButtonGroup);
            app.DERsVDERsButton.Text = 'DERs & VDERs';
            app.DERsVDERsButton.Value = true;
            app.VDERsonlyButton = uiradiobutton(app.PControlAppliedtoButtonGroup);
            app.VDERsonlyButton.Text = 'VDERs only';
            app.EnableLPFCheckBox = uicheckbox(app.GridLayout45);
            app.EnableLPFCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableLPFCheckBoxValueChanged, true);
            app.EnableLPFCheckBox.Text = 'Enable Low-Pass Filter';
            app.EnablePControlCheckBox = uicheckbox(app.GridLayout45);
            app.EnablePControlCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnablePControlCheckBoxValueChanged, true);
            app.EnablePControlCheckBox.Text = 'Enable P Control';
            app.EnableDControlCheckBox = uicheckbox(app.GridLayout45);
            app.EnableDControlCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableDControlCheckBoxValueChanged, true);
            app.EnableDControlCheckBox.Text = 'Enable D Control';
            app.DControlPanel = uipanel(app.GridLayout45);
            app.DControlPanel.Enable = 'off';
            app.DControlPanel.Title = 'D Control';
            app.GridLayout48 = uigridlayout(app.DControlPanel);
            app.Panel_40 = uipanel(app.GridLayout48);
            app.GridLayout47_2 = uigridlayout(app.Panel_40);
            app.KdEditFieldLabel = uilabel(app.GridLayout47_2);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.GridLayout47_2, 'numeric');
            app.kappa_dlambdaEditFieldLabel = uilabel(app.GridLayout47_2);
            app.kappa_dlambdaEditFieldLabel.Text = 'kappa_d,lambda';
            app.kappa_dlambdaEditField = uieditfield(app.GridLayout47_2, 'numeric');
            app.kappa_dlambdaEditField.Value = 0.001;
            app.kappa_dmuEditFieldLabel = uilabel(app.GridLayout47_2);
            app.kappa_dmuEditFieldLabel.Text = 'kappa_d,mu';
            app.kappa_dmuEditField = uieditfield(app.GridLayout47_2, 'numeric');
            app.kappa_dmuEditField.Value = 0.001;
            app.kappa_detaEditFieldLabel = uilabel(app.GridLayout47_2);
            app.kappa_detaEditFieldLabel.Text = 'kappa_d,eta';
            app.kappa_detaEditField = uieditfield(app.GridLayout47_2, 'numeric');
            app.kappa_detaEditField.Value = 0.001;
            app.kappa_dpsiEditFieldLabel = uilabel(app.GridLayout47_2);
            app.kappa_dpsiEditFieldLabel.Text = 'kappa_d,psi';
            app.kappa_dpsiEditField = uieditfield(app.GridLayout47_2, 'numeric');
            app.kappa_dpsiEditField.Value = 0.001;
            app.kappa_dgammaEditFieldLabel = uilabel(app.GridLayout47_2);
            app.kappa_dgammaEditFieldLabel.Text = 'kappa_d,gamma';
            app.kappa_dgammaEditField = uieditfield(app.GridLayout47_2, 'numeric');
            app.kappa_dgammaEditField.Value = 1e-12;
            app.kappa_dnuEditFieldLabel = uilabel(app.GridLayout47_2);
            app.kappa_dnuEditFieldLabel.Text = 'kappa_d,nu';
            app.kappa_dnuEditField = uieditfield(app.GridLayout47_2, 'numeric');
            app.kappa_dnuEditField.Value = 1e-12;
            app.kappa_dzetaEditFieldLabel = uilabel(app.GridLayout47_2);
            app.kappa_dzetaEditFieldLabel.Text = 'kappa_d,zeta';
            app.kappa_dzetaEditField = uieditfield(app.GridLayout47_2, 'numeric');
            app.kappa_dzetaEditField.Value = 1e-07;
            app.Panel_41 = uipanel(app.GridLayout48);
            app.GridLayout49 = uigridlayout(app.Panel_41);
            app.DSignalButtonGroup = uibuttongroup(app.GridLayout49);
            app.DSignalButtonGroup.Title = 'D - Signal';
            app.DControlErrorButton = uiradiobutton(app.DSignalButtonGroup);
            app.DControlErrorButton.Text = 'Error';
            app.DControlErrorButton.Value = true;
            app.DControlYButton = uiradiobutton(app.DSignalButtonGroup);
            app.DControlYButton.Text = 'Y';
            app.OtherButton = uiradiobutton(app.DSignalButtonGroup);
            app.OtherButton.Enable = 'off';
            app.OtherButton.Text = 'Other';
            app.DControlAppliedtoButtonGroup = uibuttongroup(app.GridLayout48);
            app.DControlAppliedtoButtonGroup.Title = 'Applied to';
            app.DERsVDERsButton_2 = uiradiobutton(app.DControlAppliedtoButtonGroup);
            app.DERsVDERsButton_2.Text = 'DERs & VDERs';
            app.DERsVDERsButton_2.Value = true;
            app.VDERsonlyButton_2 = uiradiobutton(app.DControlAppliedtoButtonGroup);
            app.VDERsonlyButton_2.Text = 'VDERs only';
            app.Panel_34 = uipanel(app.GridLayout38);
            app.GridLayout39 = uigridlayout(app.Panel_34);
            app.SaveButton_2 = uibutton(app.GridLayout39, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton_2.Text = 'Save';
            app.SaveAllButton = uibutton(app.GridLayout39, 'push');
            app.SaveAllButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAllButtonPushed, true);
            app.SaveAllButton.Text = 'Save All';
            app.DeleteDERButton = uibutton(app.GridLayout39, 'push');
            app.DeleteDERButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteDERButtonPushed, true);
            app.DeleteDERButton.Enable = 'off';
            app.DeleteDERButton.Text = 'Delete DER';
            app.NewDERButton = uibutton(app.GridLayout39, 'push');
            app.NewDERButton.ButtonPushedFcn = createCallbackFcn(app, @NewDERButtonPushed, true);
            app.NewDERButton.Enable = 'off';
            app.NewDERButton.Text = 'New DER';
            app.LoadConfigsButton = uibutton(app.GridLayout39, 'push');
            app.LoadConfigsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigsButtonPushed, true);
            app.LoadConfigsButton.Text = 'Load Configs.';
            app.GenerateSaveSimulationSetupDataButton = uibutton(app.GridLayout39, 'push');
            app.GenerateSaveSimulationSetupDataButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSaveSimulationSetupDataButtonPushed, true);
            app.GenerateSaveSimulationSetupDataButton.Text = 'Generate & Save  Simulation Setup Data';
            app.ManageSimulationSetupDataButton = uibutton(app.GridLayout39, 'push');
            app.ManageSimulationSetupDataButton.ButtonPushedFcn = createCallbackFcn(app, @ManageSimulationSetupDataButtonPushed, true);
            app.ManageSimulationSetupDataButton.Text = 'Manage Simulation Setup Data';
            app.LoadConfigFromSavedSimDataButton = uibutton(app.GridLayout39, 'push');
            app.LoadConfigFromSavedSimDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigFromSavedSimDataButtonPushed, true);
            app.LoadConfigFromSavedSimDataButton.Enable = 'off';
            app.LoadConfigFromSavedSimDataButton.Text = 'Load Config. From Saved Sim Data';
            app.ClearSimDataButton = uibutton(app.GridLayout39, 'push');
            app.ClearSimDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSimDataButtonPushed, true);
            app.ClearSimDataButton.Text = 'Clear SimData';
            app.OpenDSSFileTab = uitab(app.TabGroup);
            app.OpenDSSFileTab.Title = 'OpenDSS File';
            app.GridLayout22 = uigridlayout(app.OpenDSSFileTab);
            app.MATDSSOpenDSSText = uitextarea(app.GridLayout22);
            app.Panel_31 = uipanel(app.GridLayout22);
            app.GridLayout23 = uigridlayout(app.Panel_31);
            app.RefreshButton = uibutton(app.GridLayout23, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.SaveButton = uibutton(app.GridLayout23, 'push');
            app.SaveButton.Text = 'Save';
            app.Panel_54 = uipanel(app.GridLayout);
            app.GridLayout29 = uigridlayout(app.Panel_54);
            app.ProgressBar1 = uibutton(app.GridLayout29, 'push');
            app.ProgressBar1.Text = '';
            app.Panel_55 = uipanel(app.GridLayout29);
            app.GridLayout31 = uigridlayout(app.Panel_55);
            app.GotoDetailsButton = uibutton(app.GridLayout31, 'push');
            app.GotoDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @GotoDetailsButtonPushed, true);
            app.GotoDetailsButton.Text = 'Go to Details';
            app.StatusLabel = uilabel(app.GridLayout31);
            app.StatusLabel.Text = 'Ready!';
            app.ProgressBarStatus = uilabel(app.GridLayout29);
            app.ProgressBarStatus.Text = '';
            app.DetailsDERSTabGroup = uitabgroup(app.GridLayout);
            app.DetailsTab = uitab(app.DetailsDERSTabGroup);
            app.DetailsTab.Title = 'Details';
            app.GridLayout37 = uigridlayout(app.DetailsTab);
            app.DetailsTextArea = uitextarea(app.GridLayout37);
            app.DetailsTextArea.Editable = 'off';
            app.DetailsTextArea.Value = {'Program is loading.'; ''; 'Please wait!'};
            app.DERStatusTab = uitab(app.DetailsDERSTabGroup);
            app.DERStatusTab.Title = 'DER Status';
            app.GridLayout30 = uigridlayout(app.DERStatusTab);
            app.GridLayout11 = uigridlayout(app.GridLayout30);
            app.DERStatusTable = uitable(app.GridLayout11);
            app.DERStatusTable.ColumnName = {'#'; 'DER'; 'P'; 'Q'; 'ΔP'; 'ΔQ'};
            app.DERStatusTable.RowName = {};
            app.DERStatusTable.ColumnSortable = true;
            % Show the figure after all components are created
            app.MATDSSApplicationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATDSSApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MATDSSApplicationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MATDSSApplicationUIFigure)
        end
    end
end