classdef MATDSSApp_AboutWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CopyButton                  matlab.ui.control.Button
        ilyasfarhatoutlookcomLabel  matlab.ui.control.Label
        VerText                     matlab.ui.control.Label
        WelcometoIlyasGUIApplicationforIEEE39BusSystemLabel  matlab.ui.control.Label
        MATDSSLabel                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MATDSS_AppVer)
            myscreen = get(0,'ScreenSize'); % Get screen resolution info
            mywindow = [318,245]; % Set app window size here
            sp = [(myscreen(3)-mywindow(1))/2, (myscreen(4)-mywindow(2))/2]; % Calculate the correct starting point to get the app centered on the screen
            app.UIFigure.Position = [sp,mywindow]; % set the correct info in the position varible of the app!
            

            app.VerText.Text = {['Version ' num2str(MATDSS_AppVer)];...
                           '28 August 2024';...
                           'All rights reserved ©';...
                           'Developed by Ilyas Farhat';...
                           };


        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            key = event.Key;
            
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            clipboard('copy',app.ilyasfarhatoutlookcomLabel.Text);
            app.CopyButton.Text = 'copied!';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.MATDSSLabel = uilabel(app.UIFigure);
            app.MATDSSLabel.Text = 'MATDSS';
            app.WelcometoIlyasGUIApplicationforIEEE39BusSystemLabel = uilabel(app.UIFigure);
            app.WelcometoIlyasGUIApplicationforIEEE39BusSystemLabel.Text = {'Welcome to MATDSS Application!'; ''; ''; 'This program is developed as part of Ilyas''s research work towards his PhD thesis at the University of Waterloo.'};
            app.VerText = uilabel(app.UIFigure);
            app.VerText.Text = '';
            app.ilyasfarhatoutlookcomLabel = uilabel(app.UIFigure);
            app.ilyasfarhatoutlookcomLabel.Text = 'ilyas.farhat@outlook.com';
            app.CopyButton = uibutton(app.UIFigure, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATDSSApp_AboutWindow(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end