classdef MATDSSConfiguration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MATDSSTuneAssistantUIFigure    matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroupPanel                  matlab.ui.container.Panel
        TabGroupGrid                   matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        DERsTab                        matlab.ui.container.Tab
        DERsGrid                       matlab.ui.container.GridLayout
        DERsTable                      matlab.ui.control.Table
        VITab                          matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        ITrackingPanel                 matlab.ui.container.Panel
        ITrackingGrid                  matlab.ui.container.GridLayout
        ITrackingListBox               matlab.ui.control.ListBox
        VTrackingPanel                 matlab.ui.container.Panel
        VTrackingGrid                  matlab.ui.container.GridLayout
        VTrackingListBox               matlab.ui.control.ListBox
        ControlAreasTab                matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        ControllersSettingsPanel       matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        ControllersSettingsTable       matlab.ui.control.Table
        ControlAreasSettingsPanel      matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        Label                          matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        NCALabel                       matlab.ui.control.Label
        NumofConntrolAreasLabel        matlab.ui.control.Label
        CheckListOfBusesLabel          matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        CheckListofBusesButton         matlab.ui.control.Button
        UpdateListofBusesButton        matlab.ui.control.Button
        BusesControlAreasPanel         matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        ControlAreasTable              matlab.ui.control.Table
        L2CTab                         matlab.ui.container.Tab
        L2CParametersGridMask          matlab.ui.container.GridLayout
        L2CMainPanel                   matlab.ui.container.Panel
        L2CParametersGrid              matlab.ui.container.GridLayout
        DualVariablesConstantsPanel    matlab.ui.container.Panel
        ConstantsGrid_2                matlab.ui.container.GridLayout
        ConstantsParametersPanel_2     matlab.ui.container.Panel
        ConstantsParametersGrid_2      matlab.ui.container.GridLayout
        vllunitLabel_3                 matlab.ui.control.Label
        c_zetaEditField                matlab.ui.control.EditField
        underlinevEditField_2Label_3   matlab.ui.control.Label
        vllunitLabel_2                 matlab.ui.control.Label
        a_zetaEditField                matlab.ui.control.EditField
        underlinevEditField_2Label_2   matlab.ui.control.Label
        vulunitLabel_3                 matlab.ui.control.Label
        c_nuEditField                  matlab.ui.control.EditField
        overlinevEditField_2Label_3    matlab.ui.control.Label
        vulunitLabel_2                 matlab.ui.control.Label
        a_nuEditField                  matlab.ui.control.EditField
        overlinevEditField_2Label_2    matlab.ui.control.Label
        EunitLabel_3                   matlab.ui.control.Label
        c_gammaEditField               matlab.ui.control.EditField
        c_gammaEditFieldLabel          matlab.ui.control.Label
        EunitLabel_2                   matlab.ui.control.Label
        a_gammaEditField               matlab.ui.control.EditField
        EEditField_2Label_2            matlab.ui.control.Label
        rdunitLabel_3                  matlab.ui.control.Label
        c_muEditField                  matlab.ui.control.EditField
        overliner_dEditField_2Label_3  matlab.ui.control.Label
        rdunitLabel_2                  matlab.ui.control.Label
        a_muEditField                  matlab.ui.control.EditField
        overliner_dEditField_2Label_2  matlab.ui.control.Label
        rpunitLabel_3                  matlab.ui.control.Label
        c_lambdaEditField              matlab.ui.control.EditField
        r_pEditField_2Label_3          matlab.ui.control.Label
        rpunitLabel_2                  matlab.ui.control.Label
        a_lambdaEditField              matlab.ui.control.EditField
        r_pEditField_2Label_2          matlab.ui.control.Label
        alphaunitLabel_3               matlab.ui.control.Label
        c_rhoEditField                 matlab.ui.control.EditField
        c_rhoEditFieldLabel            matlab.ui.control.Label
        alphaunitLabel_2               matlab.ui.control.Label
        a_rhoEditField                 matlab.ui.control.EditField
        alphaEditField_2Label_2        matlab.ui.control.Label
        UnitLabel_3                    matlab.ui.control.Label
        ValueLabel_3                   matlab.ui.control.Label
        ParameterLabel_3               matlab.ui.control.Label
        UnitLabel_2                    matlab.ui.control.Label
        ValueLabel_2                   matlab.ui.control.Label
        ParameterLabel_2               matlab.ui.control.Label
        GeneralL2CConstantsPanel       matlab.ui.container.Panel
        ConstantsGrid                  matlab.ui.container.GridLayout
        ConstantsParametersPanel       matlab.ui.container.Panel
        ConstantsParametersGrid        matlab.ui.container.GridLayout
        iulunitLabel                   matlab.ui.control.Label
        iulEditField                   matlab.ui.control.EditField
        overlineiEditField_2Label      matlab.ui.control.Label
        vllunitLabel                   matlab.ui.control.Label
        vllEditField                   matlab.ui.control.EditField
        underlinevEditField_2Label     matlab.ui.control.Label
        vulunitLabel                   matlab.ui.control.Label
        vulEditField                   matlab.ui.control.EditField
        overlinevEditField_2Label      matlab.ui.control.Label
        EunitLabel                     matlab.ui.control.Label
        EEditField                     matlab.ui.control.EditField
        EEditField_2Label              matlab.ui.control.Label
        rdunitLabel                    matlab.ui.control.Label
        rbardEditField                 matlab.ui.control.EditField
        overliner_dEditField_2Label    matlab.ui.control.Label
        rpunitLabel                    matlab.ui.control.Label
        rpEditField                    matlab.ui.control.EditField
        r_pEditField_2Label            matlab.ui.control.Label
        alphaunitLabel                 matlab.ui.control.Label
        alphaEditField                 matlab.ui.control.EditField
        alphaEditField_2Label          matlab.ui.control.Label
        UnitLabel                      matlab.ui.control.Label
        ValueLabel                     matlab.ui.control.Label
        ParameterLabel                 matlab.ui.control.Label
        ButtonsPanel                   matlab.ui.container.Panel
        ButtonsGrid                    matlab.ui.container.GridLayout
        DeleteDERButton                matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        SaveallButton                  matlab.ui.control.Button
        NewDERButton                   matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        AutoButton                     matlab.ui.control.Button
    end


    properties (Access = public)
        CallingApp % Description
        MATDSSExcelSheet
        ControlAreasTableData
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp)
            app.CallingApp = CallingApp;

            MATDSSConfiguration_StartupFunction(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)

            MATDSSApp_GUIChanges(app.CallingApp,'Enable_GUI');
            MATDSSApp_OpenDSSFilesListBoxValueChangedFcn(app.CallingApp); % Update the options available in the Plot Properties Panel following the saved information/configurations in teh Config files.

            app.delete;

        end

        % Button pushed function: NewDERButton
        function NewDERButtonPushed(app, event)
            i = size(app.DERsTable.Data,1) + 1;
            NewDERTableValues = {num2str(i),strcat("DER_", num2str(i)),"","0.2","PV","[1,2,3]","Wye","3","DSS_load","[20,0,0]","[2,0,0]","-1e6","1e6", "-1e6","1e6","1","1"};
            %             app.DERsTable.Data.Properties.
            app.DERsTable.Data = [app.DERsTable.Data;NewDERTableValues];
        end

        % Key release function: DERsTable
        function DERsTableKeyRelease(app, event)
            key = event.Key;

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            MATDSSConfiguration_Save(app,app.TabGroup.SelectedTab.Title)
        end

        % Button pushed function: SaveallButton
        function SaveallButtonPushed(app, event)
            for i = 1:size(app.TabGroup.Children,1)
                app.TabGroup.SelectedTab = app.TabGroup.Children(i);
                app.TabGroupSelectionChanged(app);
                MATDSSConfiguration_Save(app,app.TabGroup.Children(i).Title)
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            MATDSSConfiguration_TabSelect(app);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            [MATDSS, DER] = MATDSS_Initialize(app.CallingApp,1);
            if MATDSS.Sim.DSSStartOk % if DSS is loaded and linked successfully
                % Defining a DER device with default values at bus #
                %     DER_Bus = 52; % IEEE123Master
                [MATDSS, DER] = MATDSS_DER(app.CallingApp,MATDSS,DER);

                AllNodesNames = MATDSS.Sim.DSSCircuit.AllNodeNames;
                app.VTrackingListBox.Items = AllNodesNames(3+1:end);
                if exist([pwd '\Config\MATDSS_VI.xlsx'], 'file')

                    VIReadOptions = detectImportOptions('MATDSS_VI.xlsx','Sheet',app.MATDSSExcelSheet,'VariableNamingRule','preserve'); % set the import settings
                    VIReadOptions = setvartype(VIReadOptions,VIReadOptions.VariableNames(:),'string');
                    VITable = readtable([pwd '\Config\MATDSS_VI.xlsx'],VIReadOptions);
                    VITableData = VITable.Variables;
                    v_size = 1;
                    for i = 1:size(VITableData,1)
                        if VITableData(i,1) == "" || ismissing(VITableData(i,1))
                            break;
                        end
                        v_size = i;
                    end
                    k = MATDSS_StrComp(app.VTrackingListBox.Items,convertStringsToChars(VITableData(1:v_size,1)));
                    if k >= 1
                        app.VTrackingListBox.Value = app.VTrackingListBox.Items(k);
                    end
                end

                %                          app.VTrackingListBox.Value = app.VTrackingListBox.Items(1);
                % Get all lines/branches names
                MyLines = MATDSS.Sim.DSSCircuit.Lines;
                MyLinesAllNames = MyLines.AllNames;
                MyLinesAllPhasesNames = {};
                for i = 1:size(MyLinesAllNames,1)
                    MyLines.Name = MyLinesAllNames{i};
                    nphases = MyLines.Phases;
                    for j = 1:nphases
                        MyLinesAllPhasesNames = [MyLinesAllPhasesNames;[MyLinesAllNames{i} '.' num2str(j)]];
                    end
                end
                app.ITrackingListBox.Items = MyLinesAllPhasesNames;

                if exist([pwd '\Config\MATDSS_VI.xlsx'], 'file')

                    i_size = 1;
                    for i = 1:size(VITableData,1)
                        if VITableData(i,3) == "" || ismissing(VITableData(i,3))
                            break;
                        end
                        i_size = i;
                    end
                    k = MATDSS_StrComp(app.ITrackingListBox.Items,convertStringsToChars( VITableData(1:i_size,3)));
                    if k >= 1
                        app.ITrackingListBox.Value = app.ITrackingListBox.Items(k);
                    end
                end
            else
                app.VTrackingListBox.Items = 'Error! Could not initiate the link with OpenDSS';
                app.ITrackingListBox.Items = 'Error! Could not initiate the link with OpenDSS';
            end
        end

        % Button pushed function: DeleteDERButton
        function DeleteDERButtonPushed(app, event)
            app.DERsTable.Data(app.DERsTable.Selection(1),:) = [];
            DERsTableData = app.DERsTable.Data.Variables;
            DERsTableData (:,1) = 1:size(DERsTableData ,1);
            app.DERsTable.Data.Variables = DERsTableData ;

            if isempty(app.DERsTable.Selection)
                app.DeleteDERButton.Enable = false;
            end
        end

        % Selection changed function: DERsTable
        function DERsTableSelectionChanged(app, event)
            if ~isempty(app.DERsTable.Selection)
                app.DeleteDERButton.Enable = true;
            end
        end

        % Close request function: MATDSSTuneAssistantUIFigure
        function MATDSSTuneAssistantUIFigureCloseRequest(app, event)

            MATDSSApp_GUIChanges(app.CallingApp,'Enable_GUI');
            delete(app)

        end

        % Cell edit callback: ControlAreasTable
        function ControlAreasTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            CheckControlAreasNumbersFlag = false;
            if indices(2) == 1 || indices(2) == 2
                uiwait(msgbox('You cannot change bus names or numbers here!','Error!'));
                app.ControlAreasTable.Data = app.ControlAreasTableData;
            elseif indices(2) == 3
                if isnan(str2double(app.ControlAreasTable.Data(indices(1),indices(2)).Variables)) || mod(str2double(app.ControlAreasTable.Data(indices(1),indices(2)).Variables),1) ~= 0
                    uiwait(msgbox('Control Areas number should be positive integer','Error!'));
                    app.ControlAreasTable.Data = app.ControlAreasTableData;
                else
                    app.ControlAreasTableData = app.ControlAreasTable.Data;
                    CheckControlAreasNumbersFlag = true;
                end
            elseif indices(2) == 4
                if ~(strcmp(lower(app.ControlAreasTable.Data(indices(1),indices(2)).Variables),'f') || strcmp(lower(app.ControlAreasTable.Data(indices(1),indices(2)).Variables),'t'))
                    uiwait(msgbox('Write Either T or F!','Error!'));
                    app.ControlAreasTable.Data = app.ControlAreasTableData;
                else
                    app.ControlAreasTable.Data(indices(1),indices(2)).Variables = upper(app.ControlAreasTable.Data(indices(1),indices(2)).Variables);
                end

            elseif indices(2) == 5
                % Write code to check that the bus name written is one of
                % the names in the table.


            end
            if CheckControlAreasNumbersFlag
                CANumbers = str2double(app.ControlAreasTable.Data(:,3).Variables);
                UniqueCANumbers = unique(CANumbers);
                app.NCALabel.Text = num2str(length(UniqueCANumbers));
                ControllersSettingsTable = app.ControllersSettingsTable.Data;
                NumControllersSettings = str2double(app.ControllersSettingsTable.Data(:,1).Variables);
                for i = 1:length(UniqueCANumbers)
                    CAIndex = find(UniqueCANumbers(i) == NumControllersSettings);
                    if isempty(CAIndex)
                        CSIndex = 1;
                        for j = 1:length(NumControllersSettings)
                            if UniqueCANumbers(i) > NumControllersSettings(j)
                                CSIndex = j+1;
                            end
                        end
                        NCSEntry = [num2str(UniqueCANumbers(i));"LLC";"auto";"1e-4";"1e-3";"1e2";"1.05";"0.95";"Specified in DSS File";...
                            "1";"1"; "1e3"; "1e3"; "1e3"; "1e3"; "1e12"; "1e12"; "1e8";...
                            "1";"1";"1e-3";"1e-3";"1e-3";"1e-3";"1e-12";"1e-12";"1e-8"];
                        ControllersSettingsTable = [ControllersSettingsTable; cellstr(NCSEntry')];
                    end
                end

                app.ControllersSettingsTable.Data = ControllersSettingsTable;

                for j = 1:length(NumControllersSettings)
                    CAIndex = find(UniqueCANumbers == NumControllersSettings(j));
                    if isempty(CAIndex)
                        ControllersSettingsTable = app.ControllersSettingsTable.Data;
                        ControllersSettingsTable(j,:) = [];
                        app.ControllersSettingsTable.Data = ControllersSettingsTable;
                    end
                end


            end


        end

        % Button pushed function: UpdateListofBusesButton
        function UpdateListofBusesButtonPushed(app, event)

            % Run MATDSS quick simulation to generate list of Buses,
            % Phases and Branches

            [MATDSS, DER] = MATDSS_Initialize(app.CallingApp,1);
            if MATDSS.Sim.DSSStartOk % if DSS is loaded and linked successfully
                % Defining a DER device with default values at bus #
                %     DER_Bus = 52; % IEEE123Master
                [MATDSS, DER] = MATDSS_DER(app.CallingApp,MATDSS,DER);
                AllNodesNames = MATDSS.Sim.DSSCircuit.AllNodeNames;
                AllBusNames = MATDSS.Sim.DSSCircuit.AllBusNames; AllBusNames(1) = [];

                % Get all lines/branches names
                MyLines = MATDSS.Sim.DSSCircuit.Lines;
                MyLinesAllNames = MyLines.AllNames;
                MyLinesAllPhasesNames = {};
                for i = 1:size(MyLinesAllNames,1)
                    MyLines.Name = MyLinesAllNames{i};
                    nphases = MyLines.Phases;
                    for j = 1:nphases
                        MyLinesAllPhasesNames = [MyLinesAllPhasesNames;[MyLinesAllNames{i} '.' num2str(j)]];
                    end
                end
            else
                AllNodesNames = {'Error! Could not initiate the link with OpenDSS'};
                MyLinesAllPhasesNames = {'Error! Could not initiate the link with OpenDSS'};
            end

            AllBusNames = MATDSS.Sim.DSSCircuit.AllBusNames;

            % Control Areas Settings Section
            ControlAreasTableHeaders = {'Bus Name','Bus Number', 'Assigned Control Area (#)', 'Interface Bus (T/F)','Connected to Bus name'};
            ControlAreasTableVarTypes = repmat({'string'},1,length(ControlAreasTableHeaders));
            ControlAreasTableSize = [length(AllBusNames),length(ControlAreasTableHeaders)];
            ControlAreasTableData = repmat("",ControlAreasTableSize);
            ControlAreasTableData(:,1) = AllBusNames;
            ControlAreasTableData(:,2) = num2str([1:length(AllBusNames)]');
            ControlAreasTableData(:,3) = num2str(1);
            ControlAreasTableData(:,4) = 'F';
            ControlAreasTableData(:,5) = '';
            ControlAreasTable = cell2table(cellstr(ControlAreasTableData));
            ControlAreasTable.Properties.VariableNames = ControlAreasTableHeaders;
            writetable(ControlAreasTable,[pwd '\Config\MATDSS_ControlAreas.xlsx'],'Sheet',app.MATDSSExcelSheet,'WriteMode', 'overwritesheet','autofitwidth', 1);
            app.ControlAreasTable.Data = ControlAreasTable;
            app.ControlAreasTable.ColumnName = ControlAreasTable.Properties.VariableNames;
            app.ControlAreasTable.ColumnWidth = 'auto';
            app.ControlAreasTable.Tag = 'Loaded';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MATDSSTuneAssistantUIFigure = uifigure('Visible', 'off');
            app.MATDSSTuneAssistantUIFigure.Name = 'MATDSS Tune Assistant';
            app.MATDSSTuneAssistantUIFigure.CloseRequestFcn = createCallbackFcn(app, @MATDSSTuneAssistantUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.MATDSSTuneAssistantUIFigure);
            app.ButtonsPanel = uipanel(app.GridLayout);
            app.ButtonsGrid = uigridlayout(app.ButtonsPanel);
            app.AutoButton = uibutton(app.ButtonsGrid, 'push');
            app.AutoButton.Enable = 'off';
            app.AutoButton.Text = 'Auto';
            app.SaveButton = uibutton(app.ButtonsGrid, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RefreshButton = uibutton(app.ButtonsGrid, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Enable = 'off';
            app.RefreshButton.Text = 'Refresh';
            app.NewDERButton = uibutton(app.ButtonsGrid, 'push');
            app.NewDERButton.ButtonPushedFcn = createCallbackFcn(app, @NewDERButtonPushed, true);
            app.NewDERButton.Text = 'New DER';
            app.SaveallButton = uibutton(app.ButtonsGrid, 'push');
            app.SaveallButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallButtonPushed, true);
            app.SaveallButton.Text = 'Save all';
            app.CloseButton = uibutton(app.ButtonsGrid, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.DeleteDERButton = uibutton(app.ButtonsGrid, 'push');
            app.DeleteDERButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteDERButtonPushed, true);
            app.DeleteDERButton.Text = 'Delete DER';
            app.TabGroupPanel = uipanel(app.GridLayout);
            app.TabGroupGrid = uigridlayout(app.TabGroupPanel);
            app.TabGroup = uitabgroup(app.TabGroupGrid);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DERsTab = uitab(app.TabGroup);
            app.DERsTab.Title = 'DERs';
            app.DERsGrid = uigridlayout(app.DERsTab);
            app.DERsTable = uitable(app.DERsGrid);
            app.DERsTable.ColumnName = '';
            app.DERsTable.RowName = {};
            app.DERsTable.ColumnEditable = true;
            app.DERsTable.SelectionChangedFcn = createCallbackFcn(app, @DERsTableSelectionChanged, true);
            app.DERsTable.KeyReleaseFcn = createCallbackFcn(app, @DERsTableKeyRelease, true);
            app.VITab = uitab(app.TabGroup);
            app.VITab.Title = 'V & I';
            app.GridLayout2 = uigridlayout(app.VITab);
            app.VTrackingPanel = uipanel(app.GridLayout2);
            app.VTrackingPanel.Title = 'Voltage tracking - Buses & Phases';
            app.VTrackingGrid = uigridlayout(app.VTrackingPanel);
            app.VTrackingListBox = uilistbox(app.VTrackingGrid);
            app.VTrackingListBox.Items = {};
            app.VTrackingListBox.Multiselect = 'on';
            app.VTrackingListBox.Value = {};
            app.ITrackingPanel = uipanel(app.GridLayout2);
            app.ITrackingPanel.Title = 'Branch Current tracking - Lines';
            app.ITrackingGrid = uigridlayout(app.ITrackingPanel);
            app.ITrackingListBox = uilistbox(app.ITrackingGrid);
            app.ITrackingListBox.Items = {};
            app.ITrackingListBox.Multiselect = 'on';
            app.ITrackingListBox.Value = {};
            app.ControlAreasTab = uitab(app.TabGroup);
            app.ControlAreasTab.Title = 'Control Areas';
            app.GridLayout3 = uigridlayout(app.ControlAreasTab);
            app.ControlAreasSettingsPanel = uipanel(app.GridLayout3);
            app.ControlAreasSettingsPanel.Title = 'Control Areas Settings';
            app.GridLayout5 = uigridlayout(app.ControlAreasSettingsPanel);
            app.BusesControlAreasPanel = uipanel(app.GridLayout5);
            app.BusesControlAreasPanel.Title = 'Buses & Control Areas';
            app.GridLayout4 = uigridlayout(app.BusesControlAreasPanel);
            app.ControlAreasTable = uitable(app.GridLayout4);
            app.ControlAreasTable.ColumnName = '';
            app.ControlAreasTable.RowName = {};
            app.ControlAreasTable.ColumnEditable = true;
            app.ControlAreasTable.CellEditCallback = createCallbackFcn(app, @ControlAreasTableCellEdit, true);
            app.Panel = uipanel(app.GridLayout5);
            app.GridLayout6 = uigridlayout(app.Panel);
            app.Panel_2 = uipanel(app.GridLayout6);
            app.GridLayout8 = uigridlayout(app.Panel_2);
            app.UpdateListofBusesButton = uibutton(app.GridLayout8, 'push');
            app.UpdateListofBusesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListofBusesButtonPushed, true);
            app.UpdateListofBusesButton.Text = 'Update List of Buses';
            app.CheckListofBusesButton = uibutton(app.GridLayout8, 'push');
            app.CheckListofBusesButton.Text = 'Check List of Buses';
            app.CheckListOfBusesLabel = uilabel(app.GridLayout6);
            app.CheckListOfBusesLabel.Text = '';
            app.NumofConntrolAreasLabel = uilabel(app.GridLayout6);
            app.NumofConntrolAreasLabel.Text = 'Num of Conntrol Areas';
            app.NCALabel = uilabel(app.GridLayout6);
            app.NCALabel.Text = '';
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = 'Click on Check List of Buses button to confirm if buses listed below are updated.';
            app.ControllersSettingsPanel = uipanel(app.GridLayout3);
            app.ControllersSettingsPanel.Title = 'Controllers Settings';
            app.GridLayout7 = uigridlayout(app.ControllersSettingsPanel);
            app.ControllersSettingsTable = uitable(app.GridLayout7);
            app.ControllersSettingsTable.ColumnName = '';
            app.ControllersSettingsTable.RowName = {};
            app.ControllersSettingsTable.ColumnEditable = true;
            app.L2CTab = uitab(app.TabGroup);
            app.L2CTab.Title = 'L2C';
            app.L2CParametersGridMask = uigridlayout(app.L2CTab);
            app.L2CMainPanel = uipanel(app.L2CParametersGridMask);
            app.L2CParametersGrid = uigridlayout(app.L2CMainPanel);
            app.GeneralL2CConstantsPanel = uipanel(app.L2CParametersGrid);
            app.GeneralL2CConstantsPanel.Title = 'General L2C Constants';
            app.ConstantsGrid = uigridlayout(app.GeneralL2CConstantsPanel);
            app.ConstantsParametersPanel = uipanel(app.ConstantsGrid);
            app.ConstantsParametersGrid = uigridlayout(app.ConstantsParametersPanel);
            app.ParameterLabel = uilabel(app.ConstantsParametersGrid);
            app.ParameterLabel.Text = 'Parameter';
            app.ValueLabel = uilabel(app.ConstantsParametersGrid);
            app.ValueLabel.Text = 'Value';
            app.UnitLabel = uilabel(app.ConstantsParametersGrid);
            app.UnitLabel.Text = 'Unit';
            app.alphaEditField_2Label = uilabel(app.ConstantsParametersGrid);
            app.alphaEditField_2Label.Interpreter = 'latex';
            app.alphaEditField_2Label.Text = '$\alpha$';
            app.alphaEditField = uieditfield(app.ConstantsParametersGrid, 'text');
            app.alphaEditField.Value = 'auto';
            app.alphaunitLabel = uilabel(app.ConstantsParametersGrid);
            app.alphaunitLabel.Text = 'unitless';
            app.r_pEditField_2Label = uilabel(app.ConstantsParametersGrid);
            app.r_pEditField_2Label.Interpreter = 'latex';
            app.r_pEditField_2Label.Text = '$r_p$';
            app.rpEditField = uieditfield(app.ConstantsParametersGrid, 'text');
            app.rpEditField.Value = '1e-4';
            app.rpunitLabel = uilabel(app.ConstantsParametersGrid);
            app.rpunitLabel.Text = 'unitless';
            app.overliner_dEditField_2Label = uilabel(app.ConstantsParametersGrid);
            app.overliner_dEditField_2Label.Interpreter = 'latex';
            app.overliner_dEditField_2Label.Text = '$\overline{r}_d$';
            app.rbardEditField = uieditfield(app.ConstantsParametersGrid, 'text');
            app.rbardEditField.Value = '1e-3';
            app.rdunitLabel = uilabel(app.ConstantsParametersGrid);
            app.rdunitLabel.Text = 'unitless';
            app.EEditField_2Label = uilabel(app.ConstantsParametersGrid);
            app.EEditField_2Label.Interpreter = 'latex';
            app.EEditField_2Label.Text = '$E$';
            app.EEditField = uieditfield(app.ConstantsParametersGrid, 'text');
            app.EEditField.Value = '1e2';
            app.EunitLabel = uilabel(app.ConstantsParametersGrid);
            app.EunitLabel.Text = 'W';
            app.overlinevEditField_2Label = uilabel(app.ConstantsParametersGrid);
            app.overlinevEditField_2Label.Interpreter = 'latex';
            app.overlinevEditField_2Label.Text = '$\overline{v}$';
            app.vulEditField = uieditfield(app.ConstantsParametersGrid, 'text');
            app.vulEditField.Value = '1.05';
            app.vulunitLabel = uilabel(app.ConstantsParametersGrid);
            app.vulunitLabel.Text = 'p.u.';
            app.underlinevEditField_2Label = uilabel(app.ConstantsParametersGrid);
            app.underlinevEditField_2Label.Interpreter = 'latex';
            app.underlinevEditField_2Label.Text = '$\underline{v}$';
            app.vllEditField = uieditfield(app.ConstantsParametersGrid, 'text');
            app.vllEditField.Value = '0.95';
            app.vllunitLabel = uilabel(app.ConstantsParametersGrid);
            app.vllunitLabel.Text = 'p.u.';
            app.overlineiEditField_2Label = uilabel(app.ConstantsParametersGrid);
            app.overlineiEditField_2Label.Interpreter = 'latex';
            app.overlineiEditField_2Label.Text = '$\overline{i}$';
            app.iulEditField = uieditfield(app.ConstantsParametersGrid, 'text');
            app.iulEditField.Editable = 'off';
            app.iulEditField.Value = 'Specified in DSS File';
            app.iulunitLabel = uilabel(app.ConstantsParametersGrid);
            app.iulunitLabel.Text = 'A';
            app.DualVariablesConstantsPanel = uipanel(app.L2CParametersGrid);
            app.DualVariablesConstantsPanel.Title = 'Dual Variables Constants';
            app.ConstantsGrid_2 = uigridlayout(app.DualVariablesConstantsPanel);
            app.ConstantsParametersPanel_2 = uipanel(app.ConstantsGrid_2);
            app.ConstantsParametersGrid_2 = uigridlayout(app.ConstantsParametersPanel_2);
            app.ParameterLabel_2 = uilabel(app.ConstantsParametersGrid_2);
            app.ParameterLabel_2.Text = 'Parameter';
            app.ValueLabel_2 = uilabel(app.ConstantsParametersGrid_2);
            app.ValueLabel_2.Text = 'Value';
            app.UnitLabel_2 = uilabel(app.ConstantsParametersGrid_2);
            app.UnitLabel_2.Text = 'Unit';
            app.ParameterLabel_3 = uilabel(app.ConstantsParametersGrid_2);
            app.ParameterLabel_3.Text = 'Parameter';
            app.ValueLabel_3 = uilabel(app.ConstantsParametersGrid_2);
            app.ValueLabel_3.Text = 'Value';
            app.UnitLabel_3 = uilabel(app.ConstantsParametersGrid_2);
            app.UnitLabel_3.Text = 'Unit';
            app.alphaEditField_2Label_2 = uilabel(app.ConstantsParametersGrid_2);
            app.alphaEditField_2Label_2.Interpreter = 'latex';
            app.alphaEditField_2Label_2.Text = '$a_\rho$';
            app.a_rhoEditField = uieditfield(app.ConstantsParametersGrid_2, 'text');
            app.a_rhoEditField.Value = '1';
            app.alphaunitLabel_2 = uilabel(app.ConstantsParametersGrid_2);
            app.alphaunitLabel_2.Text = 'unitless';
            app.c_rhoEditFieldLabel = uilabel(app.ConstantsParametersGrid_2);
            app.c_rhoEditFieldLabel.Interpreter = 'latex';
            app.c_rhoEditFieldLabel.Text = '$c_\rho$';
            app.c_rhoEditField = uieditfield(app.ConstantsParametersGrid_2, 'text');
            app.c_rhoEditField.Value = '1';
            app.alphaunitLabel_3 = uilabel(app.ConstantsParametersGrid_2);
            app.alphaunitLabel_3.Text = 'unitless';
            app.r_pEditField_2Label_2 = uilabel(app.ConstantsParametersGrid_2);
            app.r_pEditField_2Label_2.Interpreter = 'latex';
            app.r_pEditField_2Label_2.Text = '$a_\lambda$';
            app.a_lambdaEditField = uieditfield(app.ConstantsParametersGrid_2, 'text');
            app.a_lambdaEditField.Value = '1';
            app.rpunitLabel_2 = uilabel(app.ConstantsParametersGrid_2);
            app.rpunitLabel_2.Text = 'unitless';
            app.r_pEditField_2Label_3 = uilabel(app.ConstantsParametersGrid_2);
            app.r_pEditField_2Label_3.Interpreter = 'latex';
            app.r_pEditField_2Label_3.Text = '$c_\lambda$';
            app.c_lambdaEditField = uieditfield(app.ConstantsParametersGrid_2, 'text');
            app.c_lambdaEditField.Value = '1';
            app.rpunitLabel_3 = uilabel(app.ConstantsParametersGrid_2);
            app.rpunitLabel_3.Text = 'unitless';
            app.overliner_dEditField_2Label_2 = uilabel(app.ConstantsParametersGrid_2);
            app.overliner_dEditField_2Label_2.Interpreter = 'latex';
            app.overliner_dEditField_2Label_2.Text = '$a_\mu$';
            app.a_muEditField = uieditfield(app.ConstantsParametersGrid_2, 'text');
            app.a_muEditField.Value = '1';
            app.rdunitLabel_2 = uilabel(app.ConstantsParametersGrid_2);
            app.rdunitLabel_2.Text = 'unitless';
            app.overliner_dEditField_2Label_3 = uilabel(app.ConstantsParametersGrid_2);
            app.overliner_dEditField_2Label_3.Interpreter = 'latex';
            app.overliner_dEditField_2Label_3.Text = '$c_\mu$';
            app.c_muEditField = uieditfield(app.ConstantsParametersGrid_2, 'text');
            app.c_muEditField.Value = '1';
            app.rdunitLabel_3 = uilabel(app.ConstantsParametersGrid_2);
            app.rdunitLabel_3.Text = 'unitless';
            app.EEditField_2Label_2 = uilabel(app.ConstantsParametersGrid_2);
            app.EEditField_2Label_2.Interpreter = 'latex';
            app.EEditField_2Label_2.Text = '$a_\gamma$';
            app.a_gammaEditField = uieditfield(app.ConstantsParametersGrid_2, 'text');
            app.a_gammaEditField.Value = '1';
            app.EunitLabel_2 = uilabel(app.ConstantsParametersGrid_2);
            app.EunitLabel_2.Interpreter = 'latex';
            app.EunitLabel_2.Text = '$W^2/V^2$';
            app.c_gammaEditFieldLabel = uilabel(app.ConstantsParametersGrid_2);
            app.c_gammaEditFieldLabel.Interpreter = 'latex';
            app.c_gammaEditFieldLabel.Text = '$c_\gamma$';
            app.c_gammaEditField = uieditfield(app.ConstantsParametersGrid_2, 'text');
            app.c_gammaEditField.Value = '1';
            app.EunitLabel_3 = uilabel(app.ConstantsParametersGrid_2);
            app.EunitLabel_3.Interpreter = 'latex';
            app.EunitLabel_3.Text = '$V^2/W^2$';
            app.overlinevEditField_2Label_2 = uilabel(app.ConstantsParametersGrid_2);
            app.overlinevEditField_2Label_2.Interpreter = 'latex';
            app.overlinevEditField_2Label_2.Text = '$a_\nu$';
            app.a_nuEditField = uieditfield(app.ConstantsParametersGrid_2, 'text');
            app.a_nuEditField.Value = '1';
            app.vulunitLabel_2 = uilabel(app.ConstantsParametersGrid_2);
            app.vulunitLabel_2.Interpreter = 'latex';
            app.vulunitLabel_2.Text = '$W^2/V^2$';
            app.overlinevEditField_2Label_3 = uilabel(app.ConstantsParametersGrid_2);
            app.overlinevEditField_2Label_3.Interpreter = 'latex';
            app.overlinevEditField_2Label_3.Text = '$c_\nu$';
            app.c_nuEditField = uieditfield(app.ConstantsParametersGrid_2, 'text');
            app.c_nuEditField.Value = '1';
            app.vulunitLabel_3 = uilabel(app.ConstantsParametersGrid_2);
            app.vulunitLabel_3.Interpreter = 'latex';
            app.vulunitLabel_3.Text = '$V^2/W^2$';
            app.underlinevEditField_2Label_2 = uilabel(app.ConstantsParametersGrid_2);
            app.underlinevEditField_2Label_2.Interpreter = 'latex';
            app.underlinevEditField_2Label_2.Text = '$a_\zeta$';
            app.a_zetaEditField = uieditfield(app.ConstantsParametersGrid_2, 'text');
            app.a_zetaEditField.Value = '1';
            app.vllunitLabel_2 = uilabel(app.ConstantsParametersGrid_2);
            app.vllunitLabel_2.Interpreter = 'latex';
            app.vllunitLabel_2.Text = '$W^2/A^2$';
            app.underlinevEditField_2Label_3 = uilabel(app.ConstantsParametersGrid_2);
            app.underlinevEditField_2Label_3.Interpreter = 'latex';
            app.underlinevEditField_2Label_3.Text = '$c_\zeta$';
            app.c_zetaEditField = uieditfield(app.ConstantsParametersGrid_2, 'text');
            app.c_zetaEditField.Value = '1';
            app.vllunitLabel_3 = uilabel(app.ConstantsParametersGrid_2);
            app.vllunitLabel_3.Interpreter = 'latex';
            app.vllunitLabel_3.Text = '$A^2/W^2$';
            % Show the figure after all components are created
            app.MATDSSTuneAssistantUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATDSSConfiguration(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MATDSSTuneAssistantUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MATDSSTuneAssistantUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MATDSSTuneAssistantUIFigure)
        end
    end
end