classdef MATDSSManageSimData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MATDSSManageSimDataUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        Panel_2                      matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        CloseButton                  matlab.ui.control.Button
        LoadCloseButton              matlab.ui.control.Button
        RenameButton                 matlab.ui.control.Button
        RefreshButton                matlab.ui.control.Button
        DeleteButton                 matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        DetailsPanel                 matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        SimDataTextArea              matlab.ui.control.TextArea
        SavedSimulationDataPanel     matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        SimDataListBox               matlab.ui.control.ListBox
    end

    % MATDSSManageSimData - A sub-application to manage simulation data
    %
    % This app allows users to load, delete, rename, and refresh simulation
    % data files used by the main MATDSS application. The interface includes
    % a list of available SimData files and provides details about the selected
    % file. The user can choose to load data or close the window without loading.
    %
    % Developed by Ilyas Farhat as part of the MATDSS application suite.
    %
    % "This application is developed by 'Ilyas Farhat' as part of his research
    % work towards his Ph.D. thesis at the University of Waterloo. Any use of
    % this app should cite the following references:"
    %
    %
    % [1] https://arxiv.org/abs/2401.09694
    %
    %   Sample: [1] I. Farhat, E. Ekomwenrenren, J. W. Simpson-Porco,
    %               E. Farantatos, M. Patel, and A. Haddadi, 'A Multi-Area
    %               Architecture for Real-Time Feedback-Based Optimization
    %               of Distribution Grids', arXiv [eess.SY]. 2024,
    %               URL: https://arxiv.org/abs/2401.09694.
    %
    %
    % [2] https://github.com/aldahabi27/MATDSS/tree/main
    %
    %   Sample: [2] I. Farhat, MATDSS Application Ver ##, 2024,
    %               URL: https://github.com/aldahabi27/MATDSS/tree/main
    %
    %
    % [3] https://hdl.handle.net/10012/20848
    %
    %   Sample: [3] I. Farhat, 'Multi-Area Architecture for Real-Time
    %               Feedback-Based Optimization of Distribution Grids,
    %               'Ph.D. dissertation, Dept. of Electrical and Computer
    %               Engineering, Univ. of Waterloo, Waterloo, ON, Canada,
    %               2024. [Online]. Available: https://hdl.handle.net/10012/20848
    %
    %
    %
    %   Last Update was on MATDSS App Ver 0.96 (03 Sept. 2024)
    %
    %   MATDSS Application
    %   Copyright (c) 2024, Ilyas Farhat
    %   by Ilyas Farhat
    %
    %   This file is part of MATDSS Application
    %   Contact the developer at ilyas.farhat@outlook.com
    %
    %
    %


    %% Properties related to the SimData management
    properties (Access = public)
        CallingApp;                   % Reference to the main MATDSS application
        FilesNameList;                % List of available SimData file names
        SimDataFilesData;             % Data of SimData files
        SimDataFilesFullAddress;      % Full file paths for SimData files
        Flag_User_Should_Load = 1;    % Flag to track user load action (1: auto-load, 2: manual load)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp, Flag_User_Should_Load)
            % Initialize the app with default or passed parameters
            %
            % Inputs:
            %   - CallingApp: Reference to the main MATDSS application.
            %   - Flag_User_Should_Load: Flag indicating whether the SimData
            %     should be loaded immediately (default is 1).
            
            % Set default value for Flag_User_Should_Load if not provided
            if nargin < 3
                Flag_User_Should_Load = 1;
            end
            app.Flag_User_Should_Load = Flag_User_Should_Load;
            app.CallingApp = CallingApp;

            % Fix window position and size to center on the screen
            myscreen = get(0,'ScreenSize'); % Get screen resolution
            mywindow = [1200,800];          % Set window size
            sp = [(myscreen(3)-mywindow(1))/2, (myscreen(4)-mywindow(2))/2]; % Calculate center
            app.MATDSSManageSimDataUIFigure.Position = [sp,mywindow]; % Center window
            pause(2); % Pause to ensure proper positioning
            
            % Check and load available SimData files
            MATDSSManageSimDataApp_CheckSimDataFiles(app);
            
            
            %% Old function Code
            %{
            if nargin < 3
                Flag_User_Should_Load = 1;
            end
            app.Flag_User_Should_Load = Flag_User_Should_Load;
            app.CallingApp = CallingApp;
            % Fixing the window location and size
            myscreen = get(0,'ScreenSize'); % Get screen resolution info
            mywindow = [1200,800]; % Set app window size here
            sp = [(myscreen(3)-mywindow(1))/2, (myscreen(4)-mywindow(2))/2]; % Calculate the correct starting point to get the app centered on the screen
            app.MATDSSManageSimDataUIFigure.Position = [sp,mywindow]; % set the correct info in the position varible of the app!
            pause(2); %This is important for the GUI positioninig code below
            MATDSSManageSimDataApp_CheckSimDataFiles(app);                
            %}
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            % Handle the event when the "Close" button is clicked
            %
            % If no SimData is loaded when expected, display a warning.
            
            if app.Flag_User_Should_Load == 2 && isempty(app.CallingApp.LoadedSimData)
                MATDSSApp_Details(app.CallingApp, {' '});
                MATDSSApp_Details(app.CallingApp, {' '});
                MATDSSApp_Details(app.CallingApp, {'WARNING! No SimData is loaded.'});
                MATDSSApp_Details(app.CallingApp, {' '});
            end
            
            % Close the app window
            delete(app);
        end

        % Button pushed function: LoadCloseButton
        function LoadCloseButtonPushed(app, event)
            % Load the selected SimData and close the app window
            %
            % Calls the main application to load the selected SimData file.
            
            MATDSSManageSimDataApp_LoadSimDataFile(app, app.CallingApp);
            delete(app);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % Refresh the list of available SimData files
            %
            % This re-scans the file system for SimData files and updates the
            % SimData list in the app.
            
            MATDSSManageSimDataApp_CheckSimDataFiles(app);
        end

        % Value changed function: SimDataListBox
        function SimDataListBoxValueChanged(app, event)
            % Handle the event when the user selects a SimData file from the list
            %
            % This updates the details panel with information about the selected
            % SimData file.
            
            MATDSSManageSimDataApp_SimDataListBoxValueChanged(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Load the selected SimData file without closing the app window
            %
            % Calls the main application to load the selected SimData file.
            
            MATDSSManageSimDataApp_LoadSimDataFile(app, app.CallingApp);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MATDSSManageSimDataUIFigure = uifigure('Visible', 'off');
            app.MATDSSManageSimDataUIFigure.Name = 'MATDSS Application - Manage SimData';
            app.GridLayout = uigridlayout(app.MATDSSManageSimDataUIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.SavedSimulationDataPanel = uipanel(app.GridLayout3);
            app.SavedSimulationDataPanel.Title = 'Saved Simulation Data';
            app.GridLayout2 = uigridlayout(app.SavedSimulationDataPanel);
            app.SimDataListBox = uilistbox(app.GridLayout2);
            app.SimDataListBox.Items = {};
            app.SimDataListBox.ValueChangedFcn = createCallbackFcn(app, @SimDataListBoxValueChanged, true);
            app.SimDataListBox.Value = {};
            app.DetailsPanel = uipanel(app.GridLayout3);
            app.DetailsPanel.Title = 'Details';
            app.GridLayout4 = uigridlayout(app.DetailsPanel);
            app.SimDataTextArea = uitextarea(app.GridLayout4);
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.Panel_2);
            app.LoadButton = uibutton(app.GridLayout5, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.DeleteButton = uibutton(app.GridLayout5, 'push');
            app.DeleteButton.Text = 'Delete';
            app.RefreshButton = uibutton(app.GridLayout5, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.RenameButton = uibutton(app.GridLayout5, 'push');
            app.RenameButton.Text = 'Rename';
            app.LoadCloseButton = uibutton(app.GridLayout5, 'push');
            app.LoadCloseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCloseButtonPushed, true);
            app.LoadCloseButton.Text = 'Load & Close';
            app.CloseButton = uibutton(app.GridLayout5, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.MATDSSManageSimDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATDSSManageSimData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MATDSSManageSimDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MATDSSManageSimDataUIFigure)
        end
    end
end